classdef MSTemplateEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSTemplateEditorUIFigure       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushToolLoad                   matlab.ui.container.toolbar.PushTool
        PushToolSave                   matlab.ui.container.toolbar.PushTool
        PushToolSaveAs                 matlab.ui.container.toolbar.PushTool
        PushToolLoadFromCarTool        matlab.ui.container.toolbar.PushTool
        PushToolLoadFromExcel          matlab.ui.container.toolbar.PushTool
        PushToolLoadFRomText           matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        DrugsandBrainStimulationCheckBox  matlab.ui.control.CheckBox
        DevelopmentandAgingCheckBox    matlab.ui.control.CheckBox
        ConsciousnessanditsDisordersCheckBox  matlab.ui.control.CheckBox
        PsychiatricDisordersCheckBox   matlab.ui.control.CheckBox
        NeurologicalDisordersCheckBox  matlab.ui.control.CheckBox
        CognitionandEmotionCheckBox    matlab.ui.control.CheckBox
        DomainsLabel                   matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        FreeKeywordsEditField          matlab.ui.control.EditField
        FreeKeywordsEditFieldLabel     matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        SpatialSmoothingCheckBox       matlab.ui.control.CheckBox
        SimilarityThresholdEditField   matlab.ui.control.NumericEditField
        SimilarityThreshold0fornoneEditFieldLabel  matlab.ui.control.Label
        BackfittingonDropDown          matlab.ui.control.DropDown
        BackfittingonDropDownLabel     matlab.ui.control.Label
        ATimeEditField                 matlab.ui.control.NumericEditField
        MeananalysistimesLabel         matlab.ui.control.Label
        NSubjectsEditField             matlab.ui.control.NumericEditField
        NsubjectsLabel                 matlab.ui.control.Label
        EyestateDropDown               matlab.ui.control.DropDown
        EyestateDropDownLabel          matlab.ui.control.Label
        BandpassFilterEditField        matlab.ui.control.EditField
        BandpassFilterEditFieldLabel   matlab.ui.control.Label
        ModelselectionDropDown         matlab.ui.control.DropDown
        ModelselectionDropDownLabel    matlab.ui.control.Label
        SoftwareusedDropDown           matlab.ui.control.DropDown
        SoftwareusedDropDownLabel      matlab.ui.control.Label
        DataselectionDropDown          matlab.ui.control.DropDown
        DataselectionDropDownLabel     matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        GridLayoutMaps                 matlab.ui.container.GridLayout
        GridReference                  matlab.ui.container.GridLayout
        DOIButton                      matlab.ui.control.Button
        EditedbyEditField              matlab.ui.control.EditField
        EditedbyEditFieldLabel         matlab.ui.control.Label
        EmailEditField                 matlab.ui.control.EditField
        EmailEditFieldLabel            matlab.ui.control.Label
        DOIEditField                   matlab.ui.control.EditField
        PagesEditField                 matlab.ui.control.EditField
        PagesEditFieldLabel            matlab.ui.control.Label
        YearEditField                  matlab.ui.control.EditField
        YearLabel                      matlab.ui.control.Label
        JournalEditField               matlab.ui.control.EditField
        JournalLabel                   matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        TitleLabel                     matlab.ui.control.Label
        AuthorsEditField               matlab.ui.control.EditField
        AuthorsLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        TheEEG   % The EEG we're working with
        MapEdits % Description
        nMaps % Description
        DefaultFileName % Description
        HasBeenEdited
        LastPath
    end
    
    methods (Access = private)
        
        function eeg = LoadEEGLabData(~,FullPath)
            inp = load(FullPath,'-mat');
            if ~isfield(inp,'EEG') && ~isfield(inp,'msinfo')
                [~,filename] = fileparts(FullPath);
                errordlg([filename ' does not contain valid data']);
                eeg = [];
                return
            else
                if isfield(inp,'EEG')
                    eeg = inp.EEG;
                else
                    eeg = inp;
                end
            end     

        end
        
        function res = UpdateFieldIfPossible(~,s,Field,Default)
            if isfield(s,Field)
                res = s.(Field);
            else
                res = Default;
            end
        end
        
        function EditsCreated = ShowMapEdit(app,i)
            MapGrid = uigridlayout(app.GridLayoutMaps);
            MapGrid.Layout.Row = i;
            MapGrid.Layout.Column = 1;
            MapGrid.BackgroundColor = [1,1,1];
            MapGrid.ColumnWidth = {'1x','5x','1x'};
            MapGrid.RowHeight   = {100};
                
            if size(app.TheEEG.msinfo.MSMaps(app.nMaps).Maps,1) >= i
                MapAxis = uiaxes(MapGrid);
                MapAxis.Layout.Row = 1;
                MapAxis.Layout.Column = 1;
                dspCMap2T(double(app.TheEEG.msinfo.MSMaps(app.nMaps).Maps(i,:))',app.TheEEG.chanlocs,'Axis',MapAxis,'FigureHandle',app.MSTemplateEditorUIFigure);
                MapAxis.Toolbar.Visible = 'off';
                MapAxis.Interactions = [];

            else
                MainLabel = uilabel(MapGrid,"Text","Class independent effects","WordWrap","on");
                MainLabel.Layout.Row     = 1;
                MainLabel.Layout.Column  = 1;
            end
            
                
            Findings = uitable(MapGrid);
            Findings.Layout.Row = 1;
            Findings.Layout.Column = 2;
            Findings.ColumnName = {'Contrast','Finding','Effect size measure','Effect size'};
            Findings.ColumnEditable = [true false true true];
            Findings.ColumnWidth = 'auto';
            Findings.RowName = [];
            Findings.UserData = [];
            Findings.CellSelectionCallback = @app.FindingCellSelectionChanged;
            Findings.CellEditCallback      = @app.FindingsFieldValueChanged;

            if isfield(app.TheEEG.msinfo.MSMaps(app.nMaps),'Findings')
                if numel(app.TheEEG.msinfo.MSMaps(app.nMaps).Findings) >= i
                    Data2Add = app.TheEEG.msinfo.MSMaps(app.nMaps).Findings{i};
                
                    if size(Data2Add,2) < 3 && size(Data2Add,1) > 1
                        Data2Add = [Data2Add repmat([{'Unknown'} {nan}],size(Data2Add,1),1)];
                    end
                    Findings.Data = Data2Add;
                end
            end
                
            ButtonGrid = uigridlayout(MapGrid);
            ButtonGrid.RowSpacing = 1;
            ButtonGrid.Padding = 1;
            ButtonGrid.Layout.Row = 1;
            ButtonGrid.Layout.Column = 3;
            ButtonGrid.RowHeight = {'1x','4x'};
            ButtonGrid.ColumnWidth = {'1x'};
            Label = uieditfield(ButtonGrid, 'text');
            Label.Layout.Row = 1;
            Label.Layout.Column = 1;
            Label.HorizontalAlignment = 'center';
            Label.ValueChangedFcn = @app.FindingsFieldValueChanged;
            if app.nMaps >= i

                if isfield(app.TheEEG.msinfo.MSMaps(app.nMaps),'Labels')
                    Label.Value = app.TheEEG.msinfo.MSMaps(app.nMaps).Labels{i};
                else
                    Label.Value = sprintf('Class %i',i);
                end
            else
                Label.Value = 'Across classes';
            end
            ButtonSubGrid = uigridlayout(ButtonGrid,"RowSpacing",1,"Padding",1,"RowHeight",{'1x','1x','1x'},"ColumnWidth",{'1x','1x'});
            ButtonSubGrid.Layout.Row = 2;
            ButtonSubGrid.Layout.Column = 1;
            
            AddButton = uibutton(ButtonSubGrid, "Text",'Add');
            AddButton.Layout.Row = 1;
            AddButton.Layout.Column = 1;
            AddButton.ButtonPushedFcn = {@app.AddFinding,Findings};

            EditButton = uibutton(ButtonSubGrid,"Text",'Edit');
            EditButton.Layout.Row = 2;
            EditButton.Layout.Column = 1;
            EditButton.ButtonPushedFcn = {@app.AddFinding,Findings,true};


            RemoveButton = uibutton(ButtonSubGrid,"Text",'Remove');
            RemoveButton.Layout.Row = 3;
            RemoveButton.Layout.Column = 1;
            RemoveButton.ButtonPushedFcn = {@app.RemoveFinding,Findings};

            if i > 1 & i <= app.nMaps
                UpButton = uibutton(ButtonSubGrid, "Text",'Up');
                UpButton.Layout.Row = 1;
                UpButton.Layout.Column = 2;
                UpButton.ButtonPushedFcn = {@app.SwapMaps,i,i-1};
            end

            if i < app.nMaps
                UpButton = uibutton(ButtonSubGrid, "Text",'Down');
                UpButton.Layout.Row = 2;
                UpButton.Layout.Column = 2;
                UpButton.ButtonPushedFcn = {@app.SwapMaps,i,i+1};
            end
            
            
            EditsCreated = struct('Labels',Label,'Findings',Findings);

        end

        function SwapMaps(app,source,event,i,j)
            app.TheEEG.msinfo.MSMaps(app.nMaps).Maps([i,j],:) = app.TheEEG.msinfo.MSMaps(app.nMaps).Maps([j,i],:);
            app.TheEEG.msinfo.MSMaps(app.nMaps).Labels([i,j]) = app.TheEEG.msinfo.MSMaps(app.nMaps).Labels([j,i]);
            app.TheEEG.msinfo.MSMaps(app.nMaps).Findings([i,j]) = app.TheEEG.msinfo.MSMaps(app.nMaps).Findings([j,i]);
            app.MapEdits(i) = app.ShowMapEdit(i);
            app.MapEdits(j) = app.ShowMapEdit(j);
            app.SetModified(true);
        end
        
        function LoadAndShowEEG(app, FullFileName, EEG)
            if nargin > 2
                app.TheEEG = EEG;
            else
                if nargin < 2
                    [filename, pathname] = uigetfile('*.set', 'Pick an microstate template file',app.LastPath);
 
                    if filename == 0
                        return
                    end
                    FullFileName = fullfile(pathname,filename);
                end
                eeg = app.LoadEEGLabData(FullFileName);
                if ~isempty(eeg)
                    app.TheEEG = eeg;
                else
                    return
                end
            end
            
            if ~isempty(FullFileName)
                app.LastPath = fileparts(FullFileName);
                app.DefaultFileName = FullFileName;
            end
            
            if isfield(app.TheEEG.msinfo,'Citation')
                app.AuthorsEditField.Value                     = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Authors','');
                app.TitleEditField.Value                       = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Title','');
                app.JournalEditField.Value                     = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Journal','');
                app.YearEditField.Value                        = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Year','');
                app.PagesEditField.Value                       = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Pages','');
                app.DOIEditField.Value                         = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'DOI','');
                app.EditedbyEditField.Value                    = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Editor','');
                app.EmailEditField.Value                       = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'EMail','');
                app.DrugsandBrainStimulationCheckBox.Value     = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Drugsandbrainstimulation',false);
                app.DevelopmentandAgingCheckBox.Value          = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Development',false);
                app.ConsciousnessanditsDisordersCheckBox.Value = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Consciousnessanditsdisorders',false);
                app.PsychiatricDisordersCheckBox.Value         = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Psychiatricdisorders',false);
                app.NeurologicalDisordersCheckBox.Value        = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'Neurologicaldisorders',false);
                app.CognitionandEmotionCheckBox.Value          = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'CognitionandEmotion',false);
                app.FreeKeywordsEditField.Value                = app.UpdateFieldIfPossible(app.TheEEG.msinfo.Citation,'FreeKeyWords','');
            else
                app.AuthorsEditField.Value   = '';
                app.TitleEditField.Value     = '';
                app.JournalEditField.Value   = '';
                app.YearEditField.Value      = '';
                app.PagesEditField.Value     = '';
                app.DOIEditField.Value       = '';
                app.EditedbyEditField.Value  = '';
                app.EmailEditField.Value     = '';
                app.DrugsandBrainStimulationCheckBox.Value     = false;
                app.DevelopmentandAgingCheckBox.Value          = false;
                app.ConsciousnessanditsDisordersCheckBox.Value = false;
                app.PsychiatricDisordersCheckBox.Value         = false;
                app.NeurologicalDisordersCheckBox.Value        = false;
                app.CognitionandEmotionCheckBox.Value          = false;
                app.FreeKeywordsEditField.Value = '';
            end
        
            if isfield(app.TheEEG.msinfo,'MetaData')
                app.TheEEG.msinfo.MetaData
                app.AlgorithmDropDown.Value       = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'AlgorithmUsed','');
                app.DataselectionDropDown.Value   = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'DataSelection','');
                app.BackfittingonDropDown.Value   = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'BackFittingDataSelection','');
                app.SoftwareusedDropDown.Value    = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'SoftwareUsed','');
                app.ModelselectionDropDown.Value  = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'ModelSelection','');
                app.BandpassFilterEditField.Value = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'BandPassFilter','');
                app.EyestateDropDown.Value        = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'EyeState','');
                app.NSubjectsEditField.Value      = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'nSubjects',0);
                app.ATimeEditField.Value          = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'MeanTime',0);
                app.SpatialSmoothingCheckBox.Value = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'SpatialSmoothing',false);
                app.SimilarityThresholdEditField.Value = app.UpdateFieldIfPossible(app.TheEEG.msinfo.MetaData,'SimilarityThreshold',0);
            else
                app.AlgorithmDropDown.Value       = 'k-Means';
                app.DataselectionDropDown.Value   = 'GFP-Peaks';
                app.SoftwareusedDropDown.Value    = 'CarTool';
                app.ModelselectionDropDown.Value  = 'Previous literature';
                app.BandpassFilterEditField.Value = '';
                app.EyestateDropDown.Value        = 'Eyes open';
                app.NSubjectsEditField.Value      = 0;
                app.ATimeEditField.Value          = 0;
                app.BackfittingonDropDown.Value   = 'GFP-Peaks';
                app.SpatialSmoothingCheckBox.Value = false;
                app.SimilarityThresholdEditField.Value = 0;
            end
            
            if app.TheEEG.msinfo.ClustPar.MinClasses ~= app.TheEEG.msinfo.ClustPar.MaxClasses
                res = inputdlg({'Select number of clusters'},sprintf('Which solution (%i-%i) shall be used?',app.TheEEG.msinfo.ClustPar.MinClasses,app.TheEEG.msinfo.ClustPar.MaxClasses));
                MapIdx = str2double(res{1});
                if MapIdx < app.TheEEG.msinfo.ClustPar.MinClasses | MapIdx > app.TheEEG.msinfo.ClustPar.MaxClasses
                    errordlg('Invalid number of clusters');
                    return;
                end
                EmptyMaps = app.TheEEG.msinfo.MSMaps(1);
                %MapsToUse = TheEEG.msinfo.MSMaps(MapIdx);
                idx = 1:numel(app.TheEEG.msinfo.MSMaps);
                idx(idx == MapIdx) = [];
                for i = idx
                    app.TheEEG.msinfo.MSMaps(i) = EmptyMaps;
                end
                app.TheEEG.msinfo.ClustPar.MinClasses = MapIdx;
                app.TheEEG.msinfo.ClustPar.MaxClasses = MapIdx;
            else
                MapIdx = app.TheEEG.msinfo.ClustPar.MinClasses;
            end
            
            if (MapIdx ~= app.TheEEG.msinfo.ClustPar.MaxClasses) || isempty(app.TheEEG.msinfo.MSMaps(MapIdx))
                errordlg(sprintf('There is a problem with the templates in %s',FullFileName));
                return
            end
            
            if ~isfield(app.TheEEG.msinfo.MSMaps(MapIdx),'Labels')
                for i = 1:MapIdx
                    app.TheEEG.msinfo.MSMaps(MapIdx).Labels(i) = {sprintf('Class_%i',i)};
                end
            end

            if ~isfield(app.TheEEG.msinfo.MSMaps(MapIdx),'Findings')
                for i = 1:MapIdx+1
                    app.TheEEG.msinfo.MSMaps(MapIdx).Findings(i) = {[]};
                end
            end
            app.MapEdits = [];
            app.nMaps = MapIdx;
            app.GridLayoutMaps.ColumnWidth = {'1x'};
            app.GridLayoutMaps.RowHeight   = repmat({120},1,MapIdx+1);
            app.GridLayout.Scrollable = true;
            for i = 1:MapIdx + 1
                s = app.ShowMapEdit(i);
                app.MapEdits = [app.MapEdits s];

            end
            app.SetModified(false);
        end
        
        function RemoveFinding(app,source,event,tbl)
            if isempty(tbl.UserData)
                msgbox("No finding selected","modal");
                return
            end

            tbl.Data(tbl.UserData(1),:) = [];
            app.HasBeenEdited = true;
        end

        function FindingCellSelectionChanged(app,source,event)
            source.UserData = event.Indices;
        end

        function FindingsFieldValueChanged(app,source,event)
            app.SetModified(true);
        end

        function SetModified(app,Modified)
            if Modified == true
                if app.HasBeenEdited == false
                    [~,fn,ext] = fileparts(app.DefaultFileName);
                    app.MSTemplateEditorUIFigure.Name = ['MSTemplateEditor: ' fn ext '*'];
                end
            else
                [~,fn,ext] = fileparts(app.DefaultFileName);
                app.MSTemplateEditorUIFigure.Name = ['MSTemplateEditor: ' fn ext];
            end
            app.HasBeenEdited = Modified;
        end

        function UpdateFinding(app,source,event,txt,dd,TableHandle,FigureHandle,ESM,ESN,DoEdit)
            if nargin < 10
                DoEdit = false;
            end
            if ~isempty(txt)
                DataToAdd(1,1) = {txt.Value};
                DataToAdd(1,2) = {dd.Value};
                DataToAdd(1,3) = {ESM.Value};
                DataToAdd(1,4) = {ESN.Value};
                if DoEdit == false
                    TableHandle.Data = [TableHandle.Data;DataToAdd];
                else
                    TableHandle.Data(TableHandle.UserData(1),:) = DataToAdd;
                end
                app.SetModified(true);
            end
            delete(FigureHandle);
        end

        function CloseAddFindingDialog(app,source,event,FigureHandle)
            delete(FigureHandle);
        end

        function AddFinding(app,source,event,TableHandle,DoEdit)
            if nargin < 5
                DoEdit = false;
            end
            if DoEdit == true & isempty(TableHandle.UserData)
                msgbox("No finding selected","modal");
                return
            end
            figh = uifigure("WindowStyle","modal","Position",[300 300 300 300],'Name','Enter Effect');
            figGrid = uigridlayout(figh);
            figGrid.ColumnWidth = {'1x'};
            figGrid.RowHeight   = {'1x','5x'};
            txt = uieditfield(figGrid);
            txt.Layout.Row = 1;
            txt.Layout.Column = 1;
            
            if DoEdit == true
                txt.Value = TableHandle.Data{TableHandle.UserData,1};
            end

            MiddleGrid = uigridlayout(figGrid);
            MiddleGrid.Layout.Row = 2;
            MiddleGrid.Layout.Column = 1;

            MiddleGrid.ColumnWidth = {'1x','1x'};
            MiddleGrid.RowHeight   = {'1x'};

            MiddleRightGrid = uigridlayout(MiddleGrid,"ColumnWidth","1x","RowHeight",{'1x','1x'},'Padding',[3 3 3 3]);
            MiddleRightGrid.Layout.Row = 1;
            MiddleRightGrid.Layout.Column = 2;
            EffectSizePanel = uipanel(MiddleRightGrid,'Title','Effect Size');
            EffectSizePanel.Layout.Row = 1;
            EffectSizePanel.Layout.Column = 1;
            
            EffectSizeGrid = uigridlayout(EffectSizePanel,"Padding",[3 3 3 3]);
           % EffectSizeGrid.Layout.Row = 1;
           % EffectSizeGrid.Layout.Column = 2;
            EffectSizeGrid.ColumnWidth = {'1x'};
            EffectSizeGrid.RowHeight   = {'1x','1x'};
            if DoEdit == true
                value = TableHandle.Data{TableHandle.UserData,3};
                if isnan(value)
                    EffectSizeMeasureDD = uidropdown(EffectSizeGrid, 'Items',{'None','r-value','t-value','d-prime','partial eta-squared'},'Editable','on');
                else
                    EffectSizeMeasureDD = uidropdown(EffectSizeGrid, 'Items',{'None','r-value','t-value','d-prime','partial eta-squared'},'Editable','on','Value',value);
                end
            else
                EffectSizeMeasureDD = uidropdown(EffectSizeGrid, "Items",{'None','r-value','t-value','d-prime','partial eta-squared'},"Editable","on");
            end

            EffectSizeMeasureDD.Layout.Row = 1;
            EffectSizeMeasureDD.Layout.Column = 1;
            EffectSizeTxt = uieditfield(EffectSizeGrid,'numeric');
            
            if DoEdit == true
                value = TableHandle.Data{TableHandle.UserData,4};
                if isnan(value)
                    value = 0;
                end
                EffectSizeTxt.Value = value;
            else
                EffectSizeTxt.Value = 0;
            end
            
            EffectSizeTxt.Layout.Row = 2;
            EffectSizeTxt.Layout.Column = 1;
            
            FindingTypes = {'Localization','More Contribution','Less Contribution','More Duration','Less Duration','More Occurrence','Less Occurrence','More GFP','Less GFP', 'More GEV','Less GEV','More current density','Less current density','More BOLD','Less BOLD','Scalp field difference'};

            if DoEdit == true
                value = TableHandle.Data{TableHandle.UserData,2};
                if ~any(strcmp(FindingTypes,value))
                    FindingTypeDD = uilistbox(MiddleGrid, 'Items',FindingTypes);
                else
                    FindingTypeDD = uilistbox(MiddleGrid, 'Items',FindingTypes,'Value',value);
                end
            else
                FindingTypeDD = uilistbox(MiddleGrid, "Items",FindingTypes);
            end

            FindingTypeDD.Layout.Row = 1;
            FindingTypeDD.Layout.Column = 1;
            btGrid = uigridlayout(MiddleRightGrid,"Padding",[1 1 1 1]);
            btGrid.Layout.Row = 2;
            btGrid.Layout.Column = 1;
            btGrid.ColumnWidth = {'1x'};
            btGrid.RowHeight   = {'1x','1x'};
            okBtn = uibutton(btGrid,"Text",'OK');
            okBtn.Layout.Row = 1;
            okBtn.Layout.Column = 1;
            okBtn.ButtonPushedFcn =  {@app.UpdateFinding,txt,FindingTypeDD, TableHandle, figh,EffectSizeMeasureDD,EffectSizeTxt, DoEdit};
            CancelBtn = uibutton(btGrid,"Text",'Cancel');
            CancelBtn.Layout.Row = 2;
            CancelBtn.Layout.Column = 1;
            CancelBtn.ButtonPushedFcn =  {@app.UpdateFinding,[],[],[],figh};
        end
        
        function SaveTemplate(app,FullFileName)
            
            app.LastPath = fileparts(FullFileName);
            app.DefaultFileName = FullFileName;

            app.TheEEG.msinfo.Citation = struct('Authors',app.AuthorsEditField.Value, ...
                                                'Title'  ,app.TitleEditField.Value, ...
                                                'Journal',app.JournalEditField.Value, ...
                                                'Year'   ,app.YearEditField.Value, ...
                                                'Pages'  ,app.PagesEditField.Value, ...
                                                'DOI'    ,app.DOIEditField.Value, ...
                                                'EMail'  ,app.EmailEditField.Value, ...
                                                'Editor' ,app.EditedbyEditField.Value, ...
                                                'Drugsandbrainstimulation',app.DrugsandBrainStimulationCheckBox.Value, ...
                                                'Development',app.DevelopmentandAgingCheckBox.Value, ...
                                                'Consciousnessanditsdisorders',app.ConsciousnessanditsDisordersCheckBox.Value, ...
                                                'Psychiatricdisorders',app.PsychiatricDisordersCheckBox.Value,...
                                                'Neurologicaldisorders',app.NeurologicalDisordersCheckBox.Value, ...
                                                'CognitionandEmotion',app.CognitionandEmotionCheckBox.Value,...
                                                'FreeKeyWords',app.FreeKeywordsEditField.Value);


            app.TheEEG.msinfo.MetaData = struct('AlgorithmUsed' ,app.AlgorithmDropDown.Value,...
                                                'DataSelection' ,app.DataselectionDropDown.Value,...
                                                'SoftwareUsed'  ,app.SoftwareusedDropDown.Value,...
                                                'ModelSelection',app.ModelselectionDropDown.Value,...
                                                'BandPassFilter',app.BandpassFilterEditField.Value,...
                                                'EyeState'      ,app.EyestateDropDown.Value, ...
                                                'nSubjects'     ,app.NSubjectsEditField.Value, ...
                                                'MeanTime'      ,app.ATimeEditField.Value, ...
                                                'BackFittingDataSelection' ,app.BackfittingonDropDown.Value, ...
                                                'SimilarityThreshold',app.SimilarityThresholdEditField.Value,...
                                                'SpatialSmoothing',app.SpatialSmoothingCheckBox.Value);
            
            for i = 1:app.nMaps
                app.TheEEG.msinfo.MSMaps(app.nMaps).Labels(i) = {app.MapEdits(i).Labels.Value};
            end
            for i = 1:app.nMaps + 1
                app.TheEEG.msinfo.MSMaps(app.nMaps).Findings(i) = {app.MapEdits(i).Findings.Data};
            end

            EEG = app.TheEEG;
            EEG.data = single(app.TheEEG.msinfo.MSMaps(app.nMaps).Maps');
            [pn,fn,ext]  = fileparts(FullFileName);
            EEG.setname  = fn;
            EEG.filename = [fn ext];
            EEG.filepath = pn;
            EEG.trials   = 1;
            EEG.pnts     = app.nMaps;
            EEG.xmin     = 1;
            EEG.xmax     = app.nMaps;
            EEG.times    = EEG.xmin:EEG.xmax;
            save(FullFileName,"EEG",'-mat');
            app.SetModified(false);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FileName)
            
            if ~ispref('MSTemplateEditor','TemplatePath')
                addpref('MSTemplateEditor','TemplatePath','');
            end
            
            app.LastPath = getpref('MSTemplateEditor','TemplatePath');
                        
            if nargin > 1
                if isstruct(FileName)
                    app.DefaultFileName = FileName.setname;
                    app.LoadAndShowEEG([],FileName);
                else
                    app.DefaultFileName = FileName;
                    app.LoadAndShowEEG(FileName);
                end
            else
%                app.PushToolLoadClicked();
            end
        end

        % Callback function: PushToolLoad
        function PushToolLoadClicked(app, event)
            if app.HasBeenEdited
                ButtonName = questdlg('What do you want to do?', ...
                             'You have unsaved changes', ...
                         'Save and continue', 'Just continue', 'Cancel', 'Save and continue');
                
               switch ButtonName
                   case 'Save and continue'
                        app.PushToolSaveAsClicked();
                        if app.HasBeenEdited == true
                            return;
                        end
                        app.LoadAndShowEEG();
                   case 'Just continue'
                        app.LoadAndShowEEG();
                    case 'Cancel'
                        return
                end % switch
            else
                app.LoadAndShowEEG();
            end                        
            
        end

        % Callback function: PushToolSaveAs
        function PushToolSaveAsClicked(app, event)
            [filename, pathname] = uiputfile('*.set', 'Save template as',fullfile(app.LastPath,app.DefaultFileName));

            if pathname == 0
                return
            end
            app.LastPath = pathname;
            app.SaveTemplate(fullfile(pathname,filename));
        end

        % Callback function: PushToolLoadFromCarTool
        function PushToolLoadFromCarToolClicked(app, event)
            [FileName,PathName] = uigetfile("*.ep","Select EP file",app.LastPath);
            if FileName == 0
                return
            else
                EPFileName = fullfile(PathName,FileName);
            end
            app.LastPath = PathName;

            [~,FileNameRoot,~] = fileparts(FileName);

            xyzFileName = fullfile(PathName,[FileNameRoot,'.xyz']);
            if ~exist(xyzFileName,"file")
                [FileName,PathName] = uigetfile("*.xyz","Select XYZ file");
                if FileName == 0
                    return
                else
                    xyzFileName = fullfile(PathName,FileName);
                end
            end


            [EEG,EPFileName] = MSTemplatesCarTool2eeglab('FileName',EPFileName,'XYZFile',xyzFileName);
            if isempty(EEG)
                return
            end
            [p,n,~] = fileparts(EPFileName);
            app.LoadAndShowEEG(fullfile(p,[n '.set']), EEG);
        end

        % Value changed function: ATimeEditField, AlgorithmDropDown, 
        % ...and 13 other components
        function ATimeEditFieldValueChanged(app, event)
            app.SetModified(true);
        end

        % Close request function: MSTemplateEditorUIFigure
        function MSTemplateEditorUIFigureCloseRequest(app, event)
            if exist(app.LastPath,'dir')
                setpref('MSTemplateEditor','TemplatePath',app.LastPath);
            end
            if app.HasBeenEdited == true
               ButtonName = questdlg('What do you want to do?', ...
                         'You have unsaved changes', ...
                         'Save and quit', 'Just quit', 'Cancel', 'Save and quit');
                
               switch ButtonName
                    case 'Save and quit'
                        app.PushToolSaveAsClicked();
                        if app.HasBeenEdited == false
                            delete(app);
                        end
                    
                    case 'Just quit'
                        delete(app);
                    
                    case 'Cancel'
                        return
                end % switch
            else
                delete(app);
            end

        end

        % Callback function: PushToolSave
        function PushToolSaveClicked(app, event)
            app.SaveTemplate(app.DefaultFileName);
        end

        % Callback function: PushToolLoadFRomText
        function PushToolLoadFRomTextClicked(app, event)
            [FileName,PathName] = uigetfile("*.txt","Select text file with maps",app.LastPath);
            if FileName == 0
                return
            else
                EPFileName = fullfile(PathName,FileName);
            end

            app.LastPath = PathName;

             [EEG,EPFileName] = MSTemplatesCarTool2eeglab('FileName',EPFileName);
    
            if isempty(EEG)
                return
            end
            [p,n,~] = fileparts(EPFileName);
            app.LoadAndShowEEG(fullfile(p,[n '.set']), EEG);

        end

        % Callback function: PushToolLoadFromExcel
        function PushToolLoadFromExcelClicked(app, event)
            [FileName,PathName] = uigetfile("*.xlsx","Select excel file with maps",app.LastPath);
            if FileName == 0
                return
            else
                EPFileName = fullfile(PathName,FileName);
            end
            
            app.LastPath = PathName;

             EEG = LoadSetFromExcelTemplate(EPFileName);
    
            if isempty(EEG)
                return
            end
            if ispref('MSTemplateEditor','Editor')
                EEG.msinfo.Citation.Editor = getpref('MSTemplateEditor','Editor');
            end
            
            [p,n,~] = fileparts(EPFileName);
            
            app.LoadAndShowEEG(fullfile(p,[n '.set']), EEG);
            app.HasBeenEdited = true;

            
        end

        % Button pushed function: DOIButton
        function DOIButtonPushed(app, event)
            DOITxt = lower(app.DOIEditField.Value);
            DOITxt = strrep(DOITxt,'https://doi.org/','');
            DOITxt = strrep(DOITxt,'doi','');
            DOITxt = strrep(DOITxt,':','');
            web(['https://doi.org/' DOITxt]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MSTemplateEditorUIFigure = uifigure('Visible', 'off');
            colormap(app.MSTemplateEditorUIFigure, 'parula');
            app.MSTemplateEditorUIFigure.Name = 'MS Template Editor';
            app.MSTemplateEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MSTemplateEditorUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.MSTemplateEditorUIFigure);
            app.PushToolLoad = uipushtool(app.Toolbar);
            app.PushToolLoad.Tooltip = {'Load a template'};
            app.PushToolLoad.ClickedCallback = createCallbackFcn(app, @PushToolLoadClicked, true);
            app.PushToolLoad.Icon = fullfile(pathToMLAPP, 'Open.jpg');
            app.PushToolSave = uipushtool(app.Toolbar);
            app.PushToolSave.Tooltip = {'Save template'};
            app.PushToolSave.ClickedCallback = createCallbackFcn(app, @PushToolSaveClicked, true);
            app.PushToolSave.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.PushToolSaveAs = uipushtool(app.Toolbar);
            app.PushToolSaveAs.Tooltip = {'Save template as'};
            app.PushToolSaveAs.ClickedCallback = createCallbackFcn(app, @PushToolSaveAsClicked, true);
            app.PushToolSaveAs.Icon = fullfile(pathToMLAPP, 'SaveAs.png');
            app.PushToolLoadFromCarTool = uipushtool(app.Toolbar);
            app.PushToolLoadFromCarTool.Tooltip = {'Import from CarTool'};
            app.PushToolLoadFromCarTool.ClickedCallback = createCallbackFcn(app, @PushToolLoadFromCarToolClicked, true);
            app.PushToolLoadFromCarTool.Icon = fullfile(pathToMLAPP, 'CarTool.png');
            app.PushToolLoadFromExcel = uipushtool(app.Toolbar);
            app.PushToolLoadFromExcel.ClickedCallback = createCallbackFcn(app, @PushToolLoadFromExcelClicked, true);
            app.PushToolLoadFromExcel.Icon = fullfile(pathToMLAPP, 'Excel.png');
            app.PushToolLoadFRomText = uipushtool(app.Toolbar);
            app.PushToolLoadFRomText.Tooltip = {'Import from text file'};
            app.PushToolLoadFRomText.ClickedCallback = createCallbackFcn(app, @PushToolLoadFRomTextClicked, true);
            app.PushToolLoadFRomText.Icon = fullfile(pathToMLAPP, 'txt.png');
            app.GridLayout = uigridlayout(app.MSTemplateEditorUIFigure);
            app.GridReference = uigridlayout(app.GridLayout);
            app.AuthorsLabel = uilabel(app.GridReference);
            app.AuthorsLabel.Text = 'Authors:';
            app.AuthorsEditField = uieditfield(app.GridReference, 'text');
            app.AuthorsEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.TitleLabel = uilabel(app.GridReference);
            app.TitleLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.GridReference, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.JournalLabel = uilabel(app.GridReference);
            app.JournalLabel.Text = 'Journal:';
            app.JournalEditField = uieditfield(app.GridReference, 'text');
            app.JournalEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.YearLabel = uilabel(app.GridReference);
            app.YearLabel.Text = 'Year:';
            app.YearEditField = uieditfield(app.GridReference, 'text');
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.PagesEditFieldLabel = uilabel(app.GridReference);
            app.PagesEditFieldLabel.Text = 'Pages:';
            app.PagesEditField = uieditfield(app.GridReference, 'text');
            app.PagesEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.DOIEditField = uieditfield(app.GridReference, 'text');
            app.DOIEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.EmailEditFieldLabel = uilabel(app.GridReference);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.EmailEditField = uieditfield(app.GridReference, 'text');
            app.EmailEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.EditedbyEditFieldLabel = uilabel(app.GridReference);
            app.EditedbyEditFieldLabel.Text = 'Edited by';
            app.EditedbyEditField = uieditfield(app.GridReference, 'text');
            app.DOIButton = uibutton(app.GridReference, 'push');
            app.DOIButton.ButtonPushedFcn = createCallbackFcn(app, @DOIButtonPushed, true);
            app.DOIButton.Text = 'DOI:';
            app.GridLayoutMaps = uigridlayout(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout2);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout2);
            app.AlgorithmDropDown.Items = {'k-Means', 'AAHC', 'Other'};
            app.AlgorithmDropDown.Editable = 'on';
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.AlgorithmDropDown.Value = 'k-Means';
            app.DataselectionDropDownLabel = uilabel(app.GridLayout2);
            app.DataselectionDropDownLabel.Text = 'Data selection';
            app.DataselectionDropDown = uidropdown(app.GridLayout2);
            app.DataselectionDropDown.Items = {'GFP-Peaks', 'All Data', 'Other (specify)'};
            app.DataselectionDropDown.Editable = 'on';
            app.DataselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.DataselectionDropDown.Value = 'GFP-Peaks';
            app.SoftwareusedDropDownLabel = uilabel(app.GridLayout2);
            app.SoftwareusedDropDownLabel.Text = 'Software used';
            app.SoftwareusedDropDown = uidropdown(app.GridLayout2);
            app.SoftwareusedDropDown.Items = {'CarTool', 'EEGLAB Plugin Koenig', 'EEGLAB Plugin Poulsen', 'Plus Microstates', 'PycoStates', 'Vision Analyzer Transformation', 'Other (specify)'};
            app.SoftwareusedDropDown.Editable = 'on';
            app.SoftwareusedDropDown.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.SoftwareusedDropDown.Value = 'CarTool';
            app.ModelselectionDropDownLabel = uilabel(app.GridLayout2);
            app.ModelselectionDropDownLabel.Text = 'Model selection';
            app.ModelselectionDropDown = uidropdown(app.GridLayout2);
            app.ModelselectionDropDown.Items = {'Previous literature', 'Cross-validation', 'Metacriterion', 'Other (specify)'};
            app.ModelselectionDropDown.Editable = 'on';
            app.ModelselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.ModelselectionDropDown.Value = 'Previous literature';
            app.BandpassFilterEditFieldLabel = uilabel(app.GridLayout2);
            app.BandpassFilterEditFieldLabel.Text = 'Bandpass Filter';
            app.BandpassFilterEditField = uieditfield(app.GridLayout2, 'text');
            app.BandpassFilterEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.BandpassFilterEditField.Value = '1-30Hz';
            app.EyestateDropDownLabel = uilabel(app.GridLayout2);
            app.EyestateDropDownLabel.Text = 'Eye state';
            app.EyestateDropDown = uidropdown(app.GridLayout2);
            app.EyestateDropDown.Items = {'Eyes open', 'Eyes closed', 'Both'};
            app.EyestateDropDown.Editable = 'on';
            app.EyestateDropDown.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.EyestateDropDown.Value = 'Eyes open';
            app.NsubjectsLabel = uilabel(app.GridLayout2);
            app.NsubjectsLabel.Text = 'N subjects';
            app.NSubjectsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NSubjectsEditField.ValueDisplayFormat = '%i';
            app.NSubjectsEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.MeananalysistimesLabel = uilabel(app.GridLayout2);
            app.MeananalysistimesLabel.Text = 'Mean analysis time (s)';
            app.ATimeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ATimeEditField.ValueChangedFcn = createCallbackFcn(app, @ATimeEditFieldValueChanged, true);
            app.BackfittingonDropDownLabel = uilabel(app.GridLayout2);
            app.BackfittingonDropDownLabel.Text = 'Backfitting on';
            app.BackfittingonDropDown = uidropdown(app.GridLayout2);
            app.BackfittingonDropDown.Items = {'GFP-Peaks', 'All Data', 'Other (specify)'};
            app.BackfittingonDropDown.Editable = 'on';
            app.BackfittingonDropDown.Value = 'GFP-Peaks';
            app.SimilarityThreshold0fornoneEditFieldLabel = uilabel(app.GridLayout2);
            app.SimilarityThreshold0fornoneEditFieldLabel.Text = 'Similarity Threshold (0 for none)';
            app.SimilarityThresholdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SimilarityThresholdEditField.Limits = [0 1];
            app.SimilarityThresholdEditField.ValueDisplayFormat = '%3.3f';
            app.SpatialSmoothingCheckBox = uicheckbox(app.GridLayout2);
            app.SpatialSmoothingCheckBox.Text = 'Spatial Smoothing';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.FreeKeywordsEditFieldLabel = uilabel(app.GridLayout5);
            app.FreeKeywordsEditFieldLabel.Text = 'Free Keywords';
            app.FreeKeywordsEditField = uieditfield(app.GridLayout5, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.DomainsLabel = uilabel(app.GridLayout8);
            app.DomainsLabel.Text = 'Domains';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.CognitionandEmotionCheckBox = uicheckbox(app.GridLayout9);
            app.CognitionandEmotionCheckBox.Text = 'Cognition and Emotion';
            app.NeurologicalDisordersCheckBox = uicheckbox(app.GridLayout9);
            app.NeurologicalDisordersCheckBox.Text = 'Neurological Disorders';
            app.PsychiatricDisordersCheckBox = uicheckbox(app.GridLayout9);
            app.PsychiatricDisordersCheckBox.Text = 'Psychiatric Disorders';
            app.ConsciousnessanditsDisordersCheckBox = uicheckbox(app.GridLayout9);
            app.ConsciousnessanditsDisordersCheckBox.Text = 'Consciousness and its Disorders';
            app.DevelopmentandAgingCheckBox = uicheckbox(app.GridLayout9);
            app.DevelopmentandAgingCheckBox.Text = 'Development and Aging';
            app.DrugsandBrainStimulationCheckBox = uicheckbox(app.GridLayout9);
            app.DrugsandBrainStimulationCheckBox.Text = 'Drugs and Brain Stimulation';
            % Show the figure after all components are created
            app.MSTemplateEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSTemplateEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSTemplateEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSTemplateEditorUIFigure)
        end
    end
end