classdef MSTemplateExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostatetemplateexplorerUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadConfigurationMenu          matlab.ui.container.Menu
        SaveConfigurationMenu          matlab.ui.container.Menu
        ExportStudyListMenu            matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TemplatesMenu                  matlab.ui.container.Menu
        TemplateEditorMenu             matlab.ui.container.Menu
        LoadTemplatesMenu              matlab.ui.container.Menu
        AddTemplatesMenu               matlab.ui.container.Menu
        FiltertemplatesMenu            matlab.ui.container.Menu
        CleartemplatefilterMenu        matlab.ui.container.Menu
        MetaMapsMenu                   matlab.ui.container.Menu
        ComputeMetaMapsMenu            matlab.ui.container.Menu
        ReorderMetaMapsMenu            matlab.ui.container.Menu
        ExportMetaMapsMenu             matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ThreeDMDSMenu                  matlab.ui.container.Menu
        FindingsMenu                   matlab.ui.container.Menu
        ClearMenu                      matlab.ui.container.Menu
        NextbestguessMenu              matlab.ui.container.Menu
        SavetoExcelFileMenu            matlab.ui.container.Menu
        AppendtoExcelFileMenu          matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        PushToolOpen                   matlab.ui.container.toolbar.PushTool
        PushToolSave                   matlab.ui.container.toolbar.PushTool
        PushToolEdit                   matlab.ui.container.toolbar.PushTool
        PushToolCompute                matlab.ui.container.toolbar.PushTool
        PushToolAddTemplate            matlab.ui.container.toolbar.PushTool
        PushToolPlus                   matlab.ui.container.toolbar.PushTool
        PushToolMinus                  matlab.ui.container.toolbar.PushTool
        PushToolFilter                 matlab.ui.container.toolbar.PushTool
        PushToolNoFilter               matlab.ui.container.toolbar.PushTool
        FindClosestTool                matlab.ui.container.toolbar.PushTool
        PushToolBroom                  matlab.ui.container.toolbar.PushTool
        Toggle3D                       matlab.ui.container.toolbar.ToggleTool
        PushToolCluster                matlab.ui.container.toolbar.PushTool
        PushToolSwap                   matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SimilarityMatrixTab            matlab.ui.container.Tab
        GridLayoutCMat                 matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        LimitresultstoselectionButton  matlab.ui.control.Button
        UIAxesCM2                      matlab.ui.control.UIAxes
        UIAxesCM1                      matlab.ui.control.UIAxes
        UICormatAxes                   matlab.ui.control.UIAxes
        MDSTab                         matlab.ui.container.Tab
        GridLayoutMDS                  matlab.ui.container.GridLayout
        GridLayoutMDSAxControl         matlab.ui.container.GridLayout
        ButtonSwapAxes                 matlab.ui.control.Button
        SliderMDSVertical              matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        GridLayoutMDSHorRor            matlab.ui.container.GridLayout
        ButtonRotateLeft               matlab.ui.control.StateButton
        ButtonRotateRight              matlab.ui.control.StateButton
        SliderMDSHorizontal            matlab.ui.control.Slider
        MDSAxesUI                      matlab.ui.control.UIAxes
        GridLayoutMDSMaps              matlab.ui.container.GridLayout
        StudyTree                      matlab.ui.container.Tree
        FindingsTab                    matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        UITableCitations               matlab.ui.control.Table
        UITableFindings                matlab.ui.control.Table
        MetaMapTab                     matlab.ui.container.Tab
        GridLayoutMetaMaps             matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        DistMat % Description
        LastPath
        SelectedTemplatesList
        SelectedMapIndex
        DomainFilter
    end
    
    properties (Access = private)
        cmDataTip
        cmDataTipPosition
        mdsResult
        mdsHighLighter
        mdsHighLightPtr
        mdsSelectedPtr
        mdsSelectedPoints
        AddedPointsToHighLight
        CMScale
        MyTimer = 0 % Description
        ClusterMaps % Description
        MetaMapSelected % Description
        MapsToShow % Description
    end
    
    
    methods (Access = private)
        
        function Rotate3DData(app, ~, ~)
            [az,el] = view(app.MDSAxesUI);
            
            az = az + app.ButtonRotateRight.Value - app.ButtonRotateLeft.Value;
            
            if az > 180
                az = az - 360;
            end

            app.SliderMDSHorizontal.Value = -az;

            view(app.MDSAxesUI,az,el);

        end
        

        function success = ComputeDistances(app,TemplatesToAdd)
            success = false;
    
            if nargin < 2
                TemplatesToAdd = [];
            end
            
            if isempty(TemplatesToAdd)
                filename = uipickfiles('Type',{ '*.set','EEGLAB MS Templates'},'Prompt','Select the templates to include','FilterSpec',char(app.LastPath));
                if ~iscell(filename)
                   return;
                end
                
                filename = sort(filename);
            
                TemplateSet = [];

                for i = 1:numel(filename)
                    [pn,fn,ext] = fileparts(filename{i});
                    eeg = app.LoadEEGLabData(pn,[fn,ext]);
                    if ~isempty(eeg)
                        if isempty(eeg.setname)
                            eeg.setname = filename{i};
                        end
                        TemplateSet = [TemplateSet {eeg}]; %#ok<AGROW> 
                    end
                end

                nTemplates = numel(TemplateSet);
            
                if nTemplates == 0
                    return;
                end
                app.DistMat.Onset       = nan(nTemplates,1);
                app.DistMat.Offset      = nan(nTemplates,1);
                app.DistMat.nMaps       = nan(nTemplates,1);
                app.DistMat.Labels      = [];
                app.DistMat.StudyLabel  = [];
                app.DistMat.StudyIdx    = [];
                app.DistMat.TemplateMap = [];
                app.DistMat.TemplateSet = TemplateSet;
                nMaps = 0;
                

                TemplateIterator = 1:nTemplates;
            else
                TemplateSet = [app.DistMat.TemplateSet {TemplatesToAdd}];
                app.DistMat.TemplateSet = TemplateSet;
                nTemplates = numel(TemplateSet);
                TemplateIterator = nTemplates;
                nMaps = max(app.DistMat.Offset);

            end
            ProgressBar = uiprogressdlg(app.MicrostatetemplateexplorerUIFigure,'Title','Please wait','Interpreter','none');
            
            
            for i = TemplateIterator
                app.DistMat.StudyLabel = [app.DistMat.StudyLabel {TemplateSet{i}.setname}];
                MapIdx = TemplateSet{i}.msinfo.ClustPar.MinClasses;
                if (MapIdx ~= TemplateSet{i}.msinfo.ClustPar.MaxClasses) || isempty(TemplateSet{i}.msinfo.MSMaps(MapIdx))
                    errordlg(sprintf('There is a problem with the %s template',TemplateSet{i}.setname));
                    return
                end
                for j = 1:MapIdx
                    if isfield(TemplateSet{i}.msinfo.MSMaps(MapIdx),'Findings')
                        f = TemplateSet{i}.msinfo.MSMaps(MapIdx).Findings(j);
                    else
                        f = {[]};
                    end
                    s = struct('Map',double(TemplateSet{i}.msinfo.MSMaps(MapIdx).Maps(j,:)),'chanlocs',TemplateSet{i}.chanlocs,'Findings',f);
                    app.DistMat.TemplateMap = [app.DistMat.TemplateMap s];
                    app.DistMat.StudyIdx = [app.DistMat.StudyIdx i];
                end

                app.DistMat.nMaps(i) = MapIdx;
                app.DistMat.Onset(i) = nMaps + 1;
                nMaps = nMaps + MapIdx;
                app.DistMat.Offset(i) = nMaps;
                app.DistMat.Labels = [app.DistMat.Labels, TemplateSet{i}.msinfo.MSMaps(MapIdx).Labels];
            end

            CorMat = zeros(nMaps);

            if isempty(TemplatesToAdd)
                cnt = 1;
                for i = 1:nTemplates
                    idx1   = app.DistMat.Onset(i):app.DistMat.Offset(i);
    
                    c = CompareTemplateMaps(TemplateSet{i});
                    CorMat(idx1,idx1) = c;
                
                    for j = i+1:nTemplates
                        ProgressBar.Message = [TemplateSet{i}.setname ,'<->', TemplateSet{j}.setname];
                        ProgressBar.Value = cnt / ((nTemplates * (nTemplates-1))/2);
                    
                        cnt = cnt+1;
                        idx2   = app.DistMat.Onset(j):app.DistMat.Offset(j);
                        c = CompareTemplateMaps(TemplateSet{i},TemplateSet{j});
                        CorMat(idx1,idx2) = c;
                        CorMat(idx2,idx1) = c';
                    end
                end
                CorMat(eye(size(CorMat,1)) == 1) = 1;   % There are some rounding issues, it seems
                app.DistMat.SharedVar = CorMat.^2;

            else
                cnt = 1;
                idx1   = app.DistMat.Onset(nTemplates):app.DistMat.Offset(nTemplates);
                c = CompareTemplateMaps(TemplateSet{i});

                CorMat(idx1,idx1) = c;

                for j = 1:nTemplates-1
                    ProgressBar.Message = [TemplateSet{nTemplates}.setname ,'<->', TemplateSet{j}.setname];
                    ProgressBar.Value = cnt / (nTemplates-1);
                    
                    cnt = cnt+1;
                    idx2   = app.DistMat.Onset(j):app.DistMat.Offset(j);
                    c = CompareTemplateMaps(TemplateSet{nTemplates},TemplateSet{j});
                    CorMat(idx1,idx2) = c;
                    CorMat(idx2,idx1) = c';
                end
                CorMat(eye(size(CorMat,1)) == 1) = 1;   % There are some rounding issues, it seems
                OldSharedVar = app.DistMat.SharedVar;
                app.DistMat.SharedVar = CorMat.^2;
                nOldLen = size(OldSharedVar,1);
                app.DistMat.SharedVar(1:nOldLen,1:nOldLen) = OldSharedVar;
                app.AddedPointsToHighLight = idx1;
            end
            
            app.DistMat.Dist = sqrt(2- 2 * sqrt(app.DistMat.SharedVar));
            success = true;
        end
        
        function SetupMDS(app, DoFilter)
            
            if nargin < 2
                DoFilter = false;
            end

            app.PushToolBroom;

            app.StudyTree = uitree(app.GridLayoutMDS);
            app.StudyTree.Layout.Row = 1;
            app.StudyTree.Layout.Column = 1;

            app.StudyTree.SelectionChangedFcn = createCallbackFcn(app, @StudyTreeSelectionChanged, true);
            app.StudyTree.ClickedFcn = createCallbackFcn(app, @StudyTreeClicked, true);
            app.StudyTree.DoubleClickedFcn = createCallbackFcn(app, @StudyTreeDoubleClicked, true);

            if DoFilter == false
                Iterator = 1:numel(app.DistMat.StudyLabel);
            else
                Iterator = zeros(numel(app.SelectedTemplatesList.Value),1);
                for  i = 1:numel(Iterator)
                    Iterator(i) = find(strcmp(app.SelectedTemplatesList.Value{i},app.DistMat.StudyLabel));
                end
            end

            for j = 1:numel(Iterator)
                i = Iterator(j);
                p = uitreenode(app.StudyTree,'Text',app.DistMat.StudyLabel{i});
                StudyMaps = find(app.DistMat.StudyIdx == i);
                for j = StudyMaps
                    uitreenode(p,'Text',app.DistMat.Labels{j},NodeData = [j,false]);
                end
            end
            if isfield(app.DistMat,'ClusterMaps')
                p = uitreenode(app.StudyTree,'Text','Meta Cluster');
                for j = app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses:app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses
                    p1 = uitreenode(p,'Text',sprintf('%i class solution',j),NodeData = [j,true]);
                    for k = 1:j
                        uitreenode(p1,'Text',sprintf('MetaMap_%i.%i',j,k),NodeData = [j,true,k]);
                    end
                end
            end
        end

        function ShowMDS(app, DoThreeD)
            if nargin < 2
                DoThreeD = [];
            end
            if isempty(DoThreeD)
                if strcmp(app.Toggle3D.State,'on')
                    DoThreeD = true;
                else
                    DoThreeD = false;
                end
            end

            if DoThreeD == false
                nDims = 2;
            else
                nDims = 3;
            end


            app.MapsToShow = [];

            for i = 1:numel(app.StudyTree.Children)
                SingleStudy = app.StudyTree.Children(i);
                for j = 1:numel(SingleStudy.Children)
                    if isempty(SingleStudy.Children(j).Children)
                        app.MapsToShow = [app.MapsToShow,SingleStudy.Children(j).NodeData(1)];
                    end
                end
            
            end

%            app.mdsResult = mdscale(app.DistMat.Dist,nDims,'Criterion','stress','Start','random','Options',statset("MaxIter",10000));
            app.mdsResult = mdscale(app.DistMat.Dist(app.MapsToShow,app.MapsToShow),nDims,'Criterion','sstress','Start','cmdscale','Options',statset("MaxIter",100000));

            if isempty(app.AddedPointsToHighLight)
                if DoThreeD == false
                    plot(app.MDSAxesUI,app.mdsResult(:,1),app.mdsResult(:,2),'ok','MarkerSize',6,'MarkerFaceColor',[0 0 0]);
                else
                    plot3(app.MDSAxesUI,app.mdsResult(:,1),app.mdsResult(:,2),app.mdsResult(:,3),'ok','MarkerSize',6,'MarkerFaceColor',[0 0 0]);
                end
                
                app.mdsSelectedPoints = [];

                hold(app.MDSAxesUI,'on');
                nMaps = numel(app.mdsSelectedPtr);

                Colors = lines(nMaps);
                for i = 1:nMaps
                    ColorTouse = Colors(i,:);
                    idx = app.mdsSelectedPtr(i);
                    if size(app.mdsResult,2) == 2
                        PlottedPoint =  plot(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),'o','MarkerSize',10,'MarkerFaceColor',ColorTouse);
                    else
                        PlottedPoint = plot3(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),app.mdsResult(idx,3),'o','MarkerSize',10,'MarkerFaceColor',ColorTouse);
                    end
                    app.mdsSelectedPoints = [app.mdsSelectedPoints PlottedPoint];

                end
                hold(app.MDSAxesUI,'off');

            else
                idx = false(size(app.mdsResult,1),1);
                idx(app.AddedPointsToHighLight,1) = true;
                if DoThreeD == false
                    plot(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),'ok','MarkerSize',9,'MarkerFaceColor',[0 0 0]);
                    hold(app.MDSAxesUI,"on");
                    plot(app.MDSAxesUI,app.mdsResult(~idx,1),app.mdsResult(~idx,2),'ok','MarkerSize',6,'MarkerFaceColor',[0.7 0.7 0.7]);
                else
                    plot3(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),app.mdsResult(idx,3),'ok','MarkerSize',9,'MarkerFaceColor',[0 0 0]);
                    hold(app.MDSAxesUI,"on");
                    plot3(app.MDSAxesUI,app.mdsResult(~idx,1),app.mdsResult(~idx,2),app.mdsResult(~idx,3),'ok','MarkerSize',6,'MarkerFaceColor',[0.7 0.7 0.7]);
                end
                hold(app.MDSAxesUI,"off");
            end
            mx = max(abs(app.mdsResult(:))) * 1;
            if DoThreeD == false
                axis(app.MDSAxesUI,"equal");                
            else
                axis(app.MDSAxesUI,"vis3d");
            end
            axis(app.MDSAxesUI,[-mx mx -mx mx -mx mx]);

        end
        
        function ShowCorrMatImage(app)
            ImageHandle = imagesc(app.UICormatAxes,app.DistMat.SharedVar(app.SelectedMapIndex,app.SelectedMapIndex),[app.CMScale 1]);
            ImageHandle.ButtonDownFcn = createCallbackFcn(app, @UICormatAxesButtonDown, true);
            colormap(app.UICormatAxes,"hot");
            app.UICormatAxes.TickLabelInterpreter = "none";
            app.UICormatAxes.XTick = 1:sum(app.SelectedMapIndex);
            app.UICormatAxes.YTick = 1:sum(app.SelectedMapIndex);
            app.UICormatAxes.XTickLabel = app.DistMat.Labels(app.SelectedMapIndex);
            app.UICormatAxes.YTickLabel = app.DistMat.Labels(app.SelectedMapIndex);
            app.UICormatAxes.TickDir = "out";
            app.UICormatAxes.XGrid = "on";
            app.UICormatAxes.YGrid = "on";
            app.UICormatAxes.YDir = "normal";
            app.UICormatAxes.PlotBoxAspectRatio = [1 1 1];
            axis(app.UICormatAxes,'tight');
            pmCM = iptPointerManager(app.MicrostatetemplateexplorerUIFigure);
            pmCM.enterFcn = [];
            pmCM.exitFcn  = [];
            pmCM.traverseFcn = @(hFigure, CurrentPoint) app.CMMouseMove(CurrentPoint);
            iptSetPointerBehavior(app.UICormatAxes,pmCM);
            app.cmDataTipPosition = [-1,-1];
        end
       
        function Polarity = RecomputeAssignment(app, nMapsToUse)
            if ~isfield(app.DistMat,'ClusterMaps')
                return;
            end
            if nargin < 2
                nMapsToUse = app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses:app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses; 
            end
            MapCollection = cell(app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses,1);
            Polarity      = MapCollection;

            for Set = 1:numel(app.DistMat.TemplateSet)
                ResamplingMat = MakeResampleMatrices(app.DistMat.TemplateSet{Set}.chanlocs,app.DistMat.ClusterMaps.chanlocs);
                for nMaps = nMapsToUse
                    MapsToAdd = app.DistMat.TemplateSet{Set}.msinfo.MSMaps(app.DistMat.nMaps(Set)).Maps * ResamplingMat';
                    MapsToAdd = NormDim(MapsToAdd,2);
                    MapCollection{nMaps} = [MapCollection{nMaps};  MapsToAdd];  
                end
            end
            for i = 1:numel(nMapsToUse)
                nMaps = nMapsToUse(i);
                TemplateMapsToShow = app.DistMat.ClusterMaps.msinfo.MSMaps(nMaps);

                fit = MapCollection{nMaps} * TemplateMapsToShow.Maps';
                [~,Assignment] = max(abs(fit),[],2);
                Polarity{nMaps} = sign(fit(sub2ind(size(fit),1:size(fit,1),Assignment')));
                app.DistMat.ClusterAssignment{nMaps} = Assignment;
            end
        end

        function ShowAssignment(app,SelectedSolution, TemplateIndex)            
            DoThreeD = size(app.mdsResult,2) == 3;            
            if nargin < 2
                Assignment = ones(size(app.mdsResult,1),1);
                ColorMap = [0 0 0];
                nMaps = 1;
            else
                TemplateMapsToShow = app.DistMat.ClusterMaps.msinfo.MSMaps(SelectedSolution);
                nMaps = size(TemplateMapsToShow.Maps,1);

                Assignment =  app.DistMat.ClusterAssignment{nMaps}(app.MapsToShow);
                ColorMap = TemplateMapsToShow.ColorMap;
            end
            
            if nargin > 2
                idx = Assignment == TemplateIndex;
                Assignment = ones(size(app.mdsResult,1),1);
                Assignment(idx) = 2;
                ColorMap = [0.75 0.75 0.75; ColorMap(TemplateIndex,:)];
                ColorIndex = 1:2;
                MarkerSize = [6 10];
            else
                TemplateIndex = 1:nMaps;
                ColorIndex = 1:nMaps;
                MarkerSize = ones(size(ColorIndex)) * 6;
            end
            cla(app.MDSAxesUI);
            hold(app.MDSAxesUI,'on');
            for i = ColorIndex
                idx = Assignment == i;
            
                if DoThreeD == false
                    plot(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),'ok','MarkerSize',MarkerSize(i),'MarkerFaceColor',ColorMap(i,:));
                else
                    plot3(app.MDSAxesUI,app.mdsResult(idx,1),app.mdsResult(idx,2),app.mdsResult(idx,3),'ok','MarkerSize',MarkerSize(i),'MarkerFaceColor',ColorMap(i,:));
                end

            end
            hold(app.MDSAxesUI,'off');
            mx = max(abs(app.mdsResult(:))) * 1;
            if DoThreeD == false
                axis(app.MDSAxesUI,"equal");                
            else
                axis(app.MDSAxesUI,"vis3d");
            end
            axis(app.MDSAxesUI,[-mx mx -mx mx -mx mx]);
            if nargin > 1
                delete(app.GridLayoutMDSMaps.Children);
                app.mdsSelectedPtr = [];
                delete(app.mdsSelectedPoints);
                app.mdsSelectedPtr = TemplateIndex;
                nMaps = numel(app.mdsSelectedPtr);
                app.GridLayoutMDSMaps.RowHeight = repmat({'1x'},1,nMaps);
            
                for i = TemplateIndex
                    MapAxis = uiaxes(app.GridLayoutMDSMaps);
                    MapAxis.Layout.Row = i;
                    MapAxis.Layout.Column = 1;
                    ColorTouse = TemplateMapsToShow.ColorMap(i,:);
                    tit = sprintf('MetaMap_%i.%i',nMaps,i);
                    dspCMap2T(double(TemplateMapsToShow.Maps(i,:))',app.DistMat.ClusterMaps.chanlocs,'Axis',MapAxis,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',tit,"Background",ColorTouse);
                    MapAxis.UserData = [nMaps,i];
                    MapAxis.ButtonDownFcn = createCallbackFcn(app, @MetaMapClicked, true);
                    MapAxis.Toolbar.Visible = 'off';
                    MapAxis.Interactions = [];

                end
            end

        end
        
        function C = CompareMaps(app,Map1,Map2,ChanLoc1,ChanLoc2)
            nChannels1 = size(Map1,2);
            nChannels2 = size(Map2,2);


            if isequal(ChanLoc1,ChanLoc2)
                Map1i = Map1;
                Map2i = Map2;
            else
            
                if nChannels1 > nChannels2
        
                    ResamplingMat = MakeResampleMatrices(ChanLoc1,ChanLoc2);
                    Map1i = Map1 * ResamplingMat';
                    Map2i = Map2;
                else
                    ResamplingMat = MakeResampleMatrices(ChanLoc2,ChanLoc1);
                    Map1i = Map1;
                    Map2i = Map2 * ResamplingMat';
                end
            end

            C = corr(double(Map1i)',double(Map2i)');
        end


        function MetaMapClicked(app, event)
            idx = event.Source.UserData;
            SelectedSolution = idx(1);
            SelectedMap      = idx(2);
            ResultsToShow    = find(app.DistMat.ClusterAssignment{SelectedSolution}(app.MapsToShow) == SelectedMap);
            ResultsToShow    = app.MapsToShow(ResultsToShow);
            app.UITableFindings.Data  = [];
            app.UITableCitations.Data = [];

            for i = 1:numel(ResultsToShow)
                Map = app.DistMat.TemplateMap(ResultsToShow(i));
                C = app.CompareMaps(Map.Map,app.DistMat.ClusterMaps.msinfo.MSMaps(SelectedSolution).Maps(SelectedMap,:),Map.chanlocs,app.DistMat.ClusterMaps.chanlocs);
                if~isempty(Map.Findings)
                    for j = 1:size(Map.Findings,1)
                        FieldsToAdd = [app.DistMat.Labels(ResultsToShow(i)) Map.Findings(j,1:2), {C.^2}];
                        app.UITableFindings.Data = [app.UITableFindings.Data;FieldsToAdd];
                    end

                else
                    FieldsToAdd = [app.DistMat.Labels(ResultsToShow(i)) {'No findings'} {''},{C.^2}];
                    app.UITableFindings.Data = [app.UITableFindings.Data;FieldsToAdd];
                end

                for TemplateIndex = 1:numel(app.DistMat.Onset)
                    if ResultsToShow(i) >= app.DistMat.Onset(TemplateIndex) && ResultsToShow(i) <= app.DistMat.Offset(TemplateIndex)
                        break
                    end
                end

                info = app.DistMat.TemplateSet{TemplateIndex}.msinfo;
                ID   = app.DistMat.TemplateSet{TemplateIndex}.setname;
                if isfield(info,'Citation')
                    C = info.Citation;
                    if ~isempty(app.UITableCitations.Data)
                        if ~any(strcmp(C.Title,app.UITableCitations.Data(:,1)))
                            app.UITableCitations.Data = [app.UITableCitations.Data;{ID},{C.Title},{C.Year},{C.Authors},{C.Journal},{C.Pages},{C.DOI}];
                        end    
                    else
                        app.UITableCitations.Data = [app.UITableCitations.Data;{ID},{C.Title},{C.Year},{C.Authors},{C.Journal},{C.Pages},{C.DOI}];
                    end
                end
            end
            app.MetaMapSelected = true;
        end
        
        
        function SaveResultsToExcel(app, FullPath)
            SheetLabel = app.UITableFindings.Data{1,1};
            OutCell = [{'Label'},{'Text'},{'Effect'},{'Similarity'};app.UITableFindings.Data];
            writecell(OutCell,FullPath,"FileType","spreadsheet","WriteMode","overwritesheet","Sheet",strcat("Findings_",SheetLabel));
                
            OutCell = [{'ID'},{'Title'},{'Year'},{'Authors'},{'Journal'},{'Pages'},{'DOI'},{'Keywords'};app.UITableCitations.Data];
            writecell(OutCell,FullPath,"FileType","spreadsheet","WriteMode","overwritesheet","Sheet",strcat("Citations_",SheetLabel));

        end

        function FilterSettingsChanged(app,~,~,figh,CheckBoxes, SelectedTemplates)

            TemplateNames = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);

            if nargin < 6
                if isempty(CheckBoxes)
                    close(figh);
                    return;
                end
                for i = 1:numel(CheckBoxes)
                    app.DomainFilter(i) = CheckBoxes{i}.Value;
                end
                DomainFields = cellfun(@(x) x.UserData,CheckBoxes,'UniformOutput',false);

                close(figh);        

                Citations    = cellfun(@(x) x.msinfo.Citation,app.DistMat.TemplateSet,'UniformOutput',false);

                HasDomain = false(numel(DomainFields),numel(Citations));

                for i = 1:numel(DomainFields)
                    for j = 1:numel(Citations)
                        if isfield( Citations{j},DomainFields{i})
                            HasDomain(i,j) = Citations{j}.(DomainFields{i});
                        end
                    end                
                end

                TheFilter = repmat(app.DomainFilter,1,numel(Citations));
                TheFilter = any(HasDomain & TheFilter,1);
                ToBeUsed = find(TheFilter);
                if isempty(ToBeUsed)
                    warndlg("No data selected");
                    ToBeUsed = ones(numel(SelectedTemplates),1);
                end
            else
                ToBeUsed = zeros(numel(SelectedTemplates),1);
                for i = 1:numel(SelectedTemplates)
                    ToBeUsed(i) = find(strcmp(TemplateNames,SelectedTemplates{i}),1);
                end

            end
            app.SelectedMapIndex = false(size(app.DistMat.SharedVar,1),1);        

            for i = 1:numel(ToBeUsed)
                app.SelectedMapIndex(app.DistMat.Onset(ToBeUsed(i)):app.DistMat.Offset(ToBeUsed(i))) = true;
            end
            app.SelectedTemplatesList.Value = TemplateNames(ToBeUsed);
            app.AddedPointsToHighLight = [];
            app.SelectedMapIndex = find(app.SelectedMapIndex == true);
            app.ShowCorrMatImage();
            app.SetupMDS(true);
            app.ShowMDS();
            app.PushToolBroomClicked();
        end


        function MDSMapButtonDownFcn(~,src,~)
            src.UserData.Map = -src.UserData.Map;    
        end

        function MDSMouseMove(app, CurrentPoint)
            if isempty(app.mdsResult)
                return
            end
            if size(app.mdsResult,2) == 2
                Point = CurrentPoint(1,1:2);
                mdsCoords = app.mdsResult;
            else
                [az,el] = view(app.MDSAxesUI);
                A = viewmtx(az,el);
                Point = [CurrentPoint(1,1:3) 1] * A';
                mdsCoords = [app.mdsResult ones(size(app.mdsResult,1),1)] * A';
                Point = Point(:,1:2);
                mdsCoords = mdsCoords(:,1:2);
            end
            
            dlt = sqrt(sum((mdsCoords - repmat(Point,size(mdsCoords,1),1)) .^2,2));
            [~,idx] = min(dlt);
            app.MDSHighLight(app.MapsToShow(idx));
        end
        
        function MDSHighLight(app,idx)
            SelMapIdx = find(app.MapsToShow == idx);
            if app.mdsHighLightPtr ~= idx
                if idx > 0
                    delete(app.mdsHighLighter);
                end
                app.mdsHighLightPtr = idx;
                hold(app.MDSAxesUI,"on");
                if size(app.mdsResult,2) == 2
                    app.mdsHighLighter = plot(app.MDSAxesUI,app.mdsResult(SelMapIdx,1),app.mdsResult(SelMapIdx,2),'+k','MarkerSize',20);
                else
                    app.mdsHighLighter = plot3(app.MDSAxesUI,app.mdsResult(SelMapIdx,1),app.mdsResult(SelMapIdx,2),app.mdsResult(SelMapIdx,3),'+k','MarkerSize',20);
                end

                
                app.mdsHighLighter.DataTipTemplate.Interpreter = 'none';
                app.mdsHighLighter.DataTipTemplate.DataTipRows(1) = app.DistMat.Labels{idx};
                app.mdsHighLighter.ButtonDownFcn = createCallbackFcn(app, @MDSAxesUIButtonDown, true);
                app.mdsHighLighter.DataTipTemplate.DataTipRows(2:end) = [];
                if size(app.mdsResult,2) == 2
                    [az,~] = view(app.MDSAxesUI);
                    if az == 0
                        dt = datatip(app.mdsHighLighter,app.mdsResult(SelMapIdx,1),app.mdsResult(SelMapIdx,2));
                    else
                        dt = datatip(app.mdsHighLighter,app.mdsResult(SelMapIdx,1),app.mdsResult(SelMapIdx,2),0);
                    end
                else
                    dt = datatip(app.mdsHighLighter,app.mdsResult(SelMapIdx,1),app.mdsResult(SelMapIdx,2),app.mdsResult(SelMapIdx,3));
                end
                dt.PickableParts = 'none';
                dt.ButtonDownFcn = createCallbackFcn(app, @MDSAxesUIButtonDown, true);
                hold(app.MDSAxesUI,"off");
                
            end
        end

        function eeg = LoadEEGLabData(~,filepath,filename)
            inp = load(fullfile(filepath,filename),'-mat');

            if ~isfield(inp,'EEG')
                errordlg([filename ' does not contain valid data...']);
                eeg = [];
                return
            else
                eeg = inp.EEG;
            end
            if ~isfield(eeg,'msinfo')
                errordlg([filename ' does not contain valid data']);
                eeg = [];
                return
            end     
        end
        
        function Point = Fig2AxCoord(~,Ax,CurrentPoint, Grid)
            tp = tightPosition(Ax,IncludeLabels = false);
            AxLowLeft = tp(1:2) + Grid.InnerPosition(1:2);
            Pitch = tp(3:4) ./ [diff(Ax.XLim) diff(Ax.YLim)];            
            Point = (CurrentPoint -AxLowLeft) ./ Pitch + [Ax.XLim(1) Ax.YLim(1)];
        end
        
        function CMMouseMove(app,CurrentPoint)

            Point = app.Fig2AxCoord(app.UICormatAxes,CurrentPoint, app.GridLayoutCMat);
            
            nMaps = numel(app.SelectedMapIndex);
            x = max(min(round(Point(1)),nMaps),1);
            y = max(min(round(Point(2)),nMaps),1);
            x_idx = app.SelectedMapIndex(x);
            y_idx = app.SelectedMapIndex(y);
            if ~any([x y]- app.cmDataTipPosition)
                return
            end
            app.cmDataTipPosition = [x y];
            img = app.UICormatAxes.Children;
            if isobject(app.cmDataTip)
                delete(app.cmDataTip);
            end
            app.cmDataTip = datatip(img,x,y,"PickableParts","none");
            img.DataTipTemplate.Interpreter = 'none';

            img.DataTipTemplate.DataTipRows(1) = app.DistMat.Labels{x_idx};
            img.DataTipTemplate.DataTipRows(2) = app.DistMat.Labels{y_idx};
            img.DataTipTemplate.DataTipRows(3) = sprintf('%3.1f%%',app.DistMat.SharedVar(x_idx,y_idx)* 100);
        end
                
        function  UpdateTemplateList(app)
            TemplateNames = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);
            app.SelectedTemplatesList.Items = TemplateNames;
            app.SelectedTemplatesList.Value = TemplateNames;
            app.UpdateSelectedMaps;
        end


        function UpdateSelectedMaps(app,~)
            app.SelectedMapIndex = false(size(app.DistMat.SharedVar,1),1);
            TemplateNames = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);

            for i = 1:numel(app.SelectedTemplatesList.Value)
                idx = find(strcmp(app.SelectedTemplatesList.Value{i},TemplateNames));
                app.SelectedMapIndex(app.DistMat.Onset(idx):app.DistMat.Offset(idx)) = true;
            end
            app.SelectedMapIndex = find(app.SelectedMapIndex == true);
            app.ShowCorrMatImage();
        end
        
        function EEG = EmptySet(~, xyz,Labels)
            EEG.setname     = '';
            EEG.filename    = '';
            EEG.filepath    = '';
            EEG.subject     = '';
            EEG.group       = '';
            EEG.condition   = '';
            EEG.session     = [];
            EEG.comments    = '';
            EEG.nbchan      = 0;
            EEG.trials      = 0;
            EEG.pnts        = 0;
            EEG.srate       = 1;
            EEG.xmin        = 0;
            EEG.xmax        = 0;
            EEG.times       = [];
            EEG.data        = [];
            EEG.icaact      = [];
            EEG.icawinv     = [];
            EEG.icasphere   = [];
            EEG.icaweights  = [];
            EEG.icachansind = [];
            for i = 1:size(xyz,1)
                x = -xyz(i,1);
                y =  xyz(i,2);
                z =  xyz(i,3);
                EEG.chanlocs(i).Y = x;
                EEG.chanlocs(i).X = y;
                EEG.chanlocs(i).Z = z;
                EEG.chanlocs(i).labels = Labels{i};
                [th,phi,radius] = cart2sph(x,y,z);
                EEG.chanlocs(i).sph_theta      = th/pi*180;
            	EEG.chanlocs(i).sph_phi        = phi/pi*180;
            	EEG.chanlocs(i).sph_radius     = radius;
                EEG.chanlocs(i).theta          = -EEG.chanlocs(i).sph_theta;
                EEG.chanlocs(i).radius         = 0.5 - EEG.chanlocs(i).sph_phi/180;
                EEG.chanlocs(i).sph_theta_besa = 0;
                EEG.chanlocs(i).sph_phi_besa   = 0;
                EEG.chanlocs(i).type = [];
            end
            
            EEG.urchanlocs  = [];
            EEG.chaninfo    = [];
            EEG.ref         = [];
            EEG.event       = [];
            EEG.urevent     = [];
            EEG.eventdescription = {};
            EEG.epoch       = [];
            EEG.epochdescription = {};
            EEG.reject      = [];
            EEG.stats       = [];
            EEG.specdata    = [];
            EEG.specicaact  = [];
            EEG.splinefile  = '';
            EEG.icasplinefile = '';
            EEG.dipfit      = [];
            EEG.history     = '';
            EEG.saved       = 'no';
            EEG.etc         = [];
        end
        
        function ShowClusterMaps(app)
            if ~isfield(app.DistMat,'ClusterMaps')
                return
            end
            app.SetupMDS()
            app.GridLayoutMetaMaps.ColumnWidth = repmat({'1x'},1,app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses);
            app.GridLayoutMetaMaps.RowHeight   = repmat({'1x'},1,app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses-app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses+1);
            
            for i = app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses:app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses
                for j = 1:i
                    app.ShowSingleClusterMap(i,j);
                end          
            end
        end
    
    function ShowSingleClusterMap(app,i,j)
        ax = uiaxes(app.GridLayoutMetaMaps);
        ax.Layout.Row = i - app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses + 1;
        ax.Layout.Column = j;
        MapToShow = app.DistMat.ClusterMaps.msinfo.MSMaps(i);
        Step = max(abs(MapToShow.Maps(j,:))) / 6;
        dspMapClass(app.DistMat.ClusterMaps.chanlocs,'Map',double(MapToShow.Maps(j,:))','Axis',ax,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',MapToShow.Labels{j},"Background",MapToShow.ColorMap(j,:),'Step',Step);

%        dspCMap2T(double(MapToShow.Maps(j,:))',app.DistMat.ClusterMaps.chanlocs,'Axis',ax,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',MapToShow.Labels{j},"Background",MapToShow.ColorMap(j,:));
        ax.ButtonDownFcn = createCallbackFcn(app, @MetaMapClickedInTab, true);
        ax.UserData = [i,j];
        ax.Toolbar.Visible = 'off';
        ax.Interactions = [];    
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Arg1)
            
           app.MyTimer = timer('Period',0.25,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.Rotate3DData; % Your Timer Callback

            app.SelectedTemplatesList = uilistbox(app.GridLayout6);
            app.SelectedTemplatesList.Items = {};
            app.SelectedTemplatesList.Multiselect = 'on';
            app.SelectedTemplatesList.Layout.Row = 3;
            app.SelectedTemplatesList.Layout.Column = 1;
            %app.SelectedTemplatesList.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectedMaps, true);
            
            if ispref('MSTemplateExplorer','TemplatePath')
                app.LastPath = getpref('MSTemplateExplorer','TemplatePath');
            else
                app.LastPath = '';
            end
            app.CMScale = 0;
            app.mdsHighLightPtr = -1;

            axis(app.UIAxesCM1,"off");
            axis(app.UIAxesCM2,"off");

            if nargin > 1                
                ProgressBar = uiprogressdlg(app.MicrostatetemplateexplorerUIFigure,'Title','Loading Configuration','Message','Loading File','Interpreter','none');

                if isstruct(Arg1)
                    if ~isfield(Arg1,'DistMat')
                        errordlg('Input structure contains no valid data');
                        return
                    end
                elseif ischar(Arg1) || isstring(Arg1)
                    [~,filename] = fileparts(Arg1);

                    inp = load(Arg1,'DistMat');

                    if ~isfield(inp,'DistMat')
                        errordlg([filename ' contains no valid data']);
                        return
                    end
                    app.MicrostatetemplateexplorerUIFigure.Name = ['Microstate template explorer: ' filename];

                    app.DistMat = inp.DistMat;
                end
                ProgressBar.Message = 'Preparing Similarity Matrix';
                ProgressBar.Value   = 0.25;

                app.UpdateTemplateList();
                app.ShowCorrMatImage();
                app.AddedPointsToHighLight = [];

                ProgressBar.Message = 'Preparing MDS Display';
                ProgressBar.Value   = 0.5;

                app.SetupMDS();
                app.ShowMDS();

                ProgressBar.Message = 'Preparing Metamaps Display';
                ProgressBar.Value   = 0.75;

                app.ShowClusterMaps();

            end
            app.TabGroupSelectionChanged;
            app.MetaMapSelected = false;

        end

        % Button down function: MDSAxesUI
        function MDSAxesUIButtonDown(app, event)
            Map = app.DistMat.TemplateMap(app.mdsHighLightPtr);

            if any(app.mdsSelectedPtr == app.mdsHighLightPtr)
                return
            end
            app.mdsSelectedPtr = [app.mdsSelectedPtr app.mdsHighLightPtr];
            nMaps = numel(app.mdsSelectedPtr);
            app.GridLayoutMDSMaps.RowHeight = repmat({'1x'},1,nMaps);
            MapAxis = uiaxes(app.GridLayoutMDSMaps);
            MapAxis.Layout.Row = nMaps;
            MapAxis.Layout.Column = 1;
            MapAxis.ButtonDownFcn = @app.MDSMapButtonDownFcn;
            Colors = vga();
            ColorIndex = rem(nMaps-1,16) + 1;
            
            ColorTouse = Colors(ColorIndex,:);

            Step = max(abs(Map.Map)) / 6;
            MapAxis.UserData = dspMapClass(Map.chanlocs,'Axis',MapAxis,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',app.DistMat.Labels{app.mdsHighLightPtr},'Map',double(Map.Map)','Step',Step,"Background",ColorTouse);
%            figure(100);
%            dspCMap2T(double(Map.Map)',Map.chanlocs,'Title',app.DistMat.Labels{app.mdsHighLightPtr},"Background",ColorTouse);

            hold(app.MDSAxesUI,'on');

            PointIdx  = find(app.MapsToShow == app.mdsHighLightPtr);

            if size(app.mdsResult,2) == 2
                PlottedPoint =  plot(app.MDSAxesUI,app.mdsResult(PointIdx,1),app.mdsResult(PointIdx,2),'o','MarkerSize',10,'MarkerFaceColor',ColorTouse);
            else
                PlottedPoint = plot3(app.MDSAxesUI,app.mdsResult(PointIdx,1),app.mdsResult(PointIdx,2),app.mdsResult(PointIdx,3),'o','MarkerSize',10,'MarkerFaceColor',ColorTouse);
            end
            hold(app.MDSAxesUI,'off');

            MapAxis.Toolbar.Visible = 'off';
            MapAxis.Interactions = [];

            app.mdsSelectedPoints = [app.mdsSelectedPoints PlottedPoint];

            Similarity = app.DistMat.SharedVar(app.mdsSelectedPtr(1),app.mdsHighLightPtr) * 100;

            if~isempty(Map.Findings)
                for i = 1:size(Map.Findings,1)
                    FieldsToAdd = [app.DistMat.Labels(app.mdsHighLightPtr) Map.Findings(i,1:2) {sprintf('%3.1f%%',Similarity)}];
                    app.UITableFindings.Data = [app.UITableFindings.Data;FieldsToAdd];
                end
            else
                FieldsToAdd = [app.DistMat.Labels(app.mdsHighLightPtr) {'No findings'} {''} {sprintf('%3.1f%%',Similarity)}];
                app.UITableFindings.Data = [app.UITableFindings.Data;FieldsToAdd];
            end
            for TemplateIndex = 1:numel(app.DistMat.Onset)
                if app.mdsHighLightPtr >= app.DistMat.Onset(TemplateIndex) && app.mdsHighLightPtr <= app.DistMat.Offset(TemplateIndex)
                    break
                end
            end

                    
            info = app.DistMat.TemplateSet{TemplateIndex}.msinfo;
            ID   = app.DistMat.TemplateSet{TemplateIndex}.setname;
            if isfield(info,'Citation')
                C = info.Citation;
                if ~isempty(app.UITableCitations.Data)
                    if ~any(strcmp(C.Title,app.UITableCitations.Data(:,1)))
                        app.UITableCitations.Data = [app.UITableCitations.Data;{ID},{C.Title},{C.Year},{C.Authors},{C.Journal},{C.Pages},{C.DOI},{C.FreeKeyWords}];
                    end    
                else
                    app.UITableCitations.Data = [app.UITableCitations.Data;{ID},{C.Title},{C.Year},{C.Authors},{C.Journal},{C.Pages},{C.DOI},{C.FreeKeyWords}];
                end
            end
            app.PushToolBroom.Enable = "on";
            app.FindClosestTool.Enable = "on";
            app.ClearMenu.Enable = "on";
            app.NextbestguessMenu.Enable = "on";
        end

        % Button down function: UICormatAxes
        function UICormatAxesButtonDown(app, event)
            xy = event.IntersectionPoint;
            nMaps = numel(app.DistMat.Labels);
            x = max(min(round(xy(1)),nMaps),1);
            y = max(min(round(xy(2)),nMaps),1);

            x = app.SelectedMapIndex(x);
            y = app.SelectedMapIndex(y);

            Map1 = app.DistMat.TemplateMap(x);
            Map2 = app.DistMat.TemplateMap(y);

            dspCMap2T(double(Map1.Map)',Map1.chanlocs,'Axis',app.UIAxesCM1,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',app.DistMat.Labels{x});
            dspCMap2T(double(Map2.Map)',Map2.chanlocs,'Axis',app.UIAxesCM2,'FigureHandle',app.MicrostatetemplateexplorerUIFigure,'Title',app.DistMat.Labels{y});
        end

        % Callback function: ClearMenu, PushToolBroom
        function PushToolBroomClicked(app, event)
            delete(app.GridLayoutMDSMaps.Children);
            app.mdsSelectedPtr = [];
            delete(app.mdsSelectedPoints);
            app.UITableFindings.Data  = [];
            app.UITableCitations.Data = [];
            app.ShowAssignment();
            app.PushToolBroom.Enable = "off";
            app.FindClosestTool.Enable = "off";
            app.MetaMapSelected = false;

        end

        % Callback function: PushToolPlus
        function PushToolPlusClicked(app, event)
            CLim = app.UICormatAxes.CLim(1);
            app.CMScale = min(CLim + 0.1,0.9);
            app.UICormatAxes.CLim(1) = app.CMScale;

        end

        % Callback function: PushToolMinus
        function PushToolMinusClicked(app, event)
            CLim = app.UICormatAxes.CLim(1);
            app.CMScale = max(CLim - 0.1,0.0);
            app.UICormatAxes.CLim(1) = app.CMScale;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SimilarityMatrixTab
                SimilarityShown = 'on';
            else
                SimilarityShown = 'off';
            end
            if (selectedTab == app.MDSTab || selectedTab == app.FindingsTab) && numel(app.mdsSelectedPtr) > 0
                app.PushToolBroom.Enable = "on";
                app.ClearMenu.Enable = "on";
            else
                app.PushToolBroom.Enable = "off";
                app.ClearMenu.Enable = "off";

            end

            if (selectedTab == app.MDSTab || selectedTab == app.FindingsTab) && numel(app.mdsSelectedPtr) > 0 && app.MetaMapSelected == false
                app.FindClosestTool.Enable = "on";
                app.NextbestguessMenu.Enable = "on";
            else
                app.FindClosestTool.Enable = "off";
                app.NextbestguessMenu.Enable = "off";
            end

            
            app.PushToolPlus.Enable = SimilarityShown;
            app.PushToolMinus.Enable = SimilarityShown;
        end

        % Callback function: LoadConfigurationMenu, PushToolOpen
        function PushToolOpenClicked(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Pick a configuration file',app.LastPath);
            
            if filename ~= 0
                
                ProgressBar = uiprogressdlg(app.MicrostatetemplateexplorerUIFigure,'Title','Loading Configuration','Message','Loading File','Interpreter','none');

                
                inp = load(fullfile(pathname,filename),'DistMat');
                if ~isfield(inp,'DistMat')
                    errordlg([filename ' contains no valid data']);
                    return
                end
                app.MicrostatetemplateexplorerUIFigure.Name = ['Microstate template explorer: ' filename];
                
                
                app.DistMat = inp.DistMat;
                ProgressBar.Message = 'Preparing Similarity Matrix';
                ProgressBar.Value   = 0.25;
                app.ShowCorrMatImage();
                UpdateTemplateList(app);
                app.AddedPointsToHighLight = [];
                ProgressBar.Message = 'Preparing MDS Display';
                ProgressBar.Value   = 0.5;
                
                app.SetupMDS();
                app.ShowMDS();
                if isfield(app.DistMat,"ClusterMaps")
                    ProgressBar.Message = 'Preparing Metamaps Display';
                    ProgressBar.Value   = 0.75;
                    app.ShowClusterMaps();                
                    app.RecomputeAssignment();
                end
                app.LastPath = pathname;
            end
        end

        % Callback function: PushToolSave, SaveConfigurationMenu
        function PushToolSaveClicked(app, event)
            DistMat = app.DistMat; %#ok<ADPROPLC> 

            [filename, pathname] = uiputfile('*.mat', 'Save configuration file',app.LastPath);
            
            if filename ~= 0
                save(fullfile(pathname,filename),'DistMat');
                app.MicrostatetemplateexplorerUIFigure.Name = ['Microstate template explorer: ' filename];
                app.LastPath = pathname;
            end
        end

        % Callback function: LoadTemplatesMenu, PushToolCompute
        function PushToolComputeClicked(app, event)
            app.ComputeDistances();
            app.UpdateTemplateList();
            app.ShowCorrMatImage();
            app.SetupMDS();
            app.ShowMDS();
        end

        % Callback function: AddTemplatesMenu, PushToolAddTemplate
        function PushToolAddTemplateClicked(app, event)
            [filename, pathname] = uigetfile('*.set', 'Pick an EEG template file');
            
            if filename ~= 0
                eeg = app.LoadEEGLabData(pathname,filename);
                app.ComputeDistances(eeg);
                app.UpdateTemplateList();
                app.RecomputeAssignment();
                app.SetupMDS;
                app.ShowMDS;
                app.ShowCorrMatImage;
            end
        end

        % Callback function: PushToolEdit, TemplateEditorMenu
        function PushToolEditClicked(app, event)
            MSTemplateEditor();
        end

        % Double-clicked callback: UITableCitations
        function UITableCitationsDoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            if displayColumn == 7
            
                DOITxt = lower(app.UITableCitations.Data{displayRow,7});
                DOITxt = strrep(DOITxt,'https://doi.org/','');
                DOITxt = strrep(DOITxt,'doi','');
                DOITxt = strrep(DOITxt,':','');
                web(['https://doi.org/' DOITxt]);
            end
            if displayColumn < 3
                SetName = app.UITableCitations.Data{displayRow,1};
                SetNames = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);
                SelectedSet = find(strcmp(SetName,SetNames));

                MSTemplateEditor(app.DistMat.TemplateSet{SelectedSet});
            end
        end

        % Clicked callback: StudyTree
        function StudyTreeClicked(app, event)
            %app.MDSHighLight(event.InteractionInformation.Node.NodeData);

        end

        % Double-clicked callback: StudyTree
        function StudyTreeDoubleClicked(app, event)
            if isempty(event.InteractionInformation.Node.NodeData)
                return
            end
            if ~isstruct(event.InteractionInformation.Node.NodeData)
                NodeData = event.InteractionInformation.Node.NodeData;
                if numel(NodeData) < 3
                    app.MDSHighLight(event.InteractionInformation.Node.NodeData(1));
                    app.MDSAxesUIButtonDown([]);
                else
                    app.FindClosestTool.Enable = "off";
                    app.NextbestguessMenu.Enable = "off";

                    app.ShowAssignment(NodeData(1),NodeData(3));
                    event.Source.UserData = NodeData([1,3]);
                    app.MetaMapClicked(event);
                end
            end
        end

        % Selection changed function: StudyTree
        function StudyTreeSelectionChanged(app, event)
            if isempty(event.SelectedNodes(1).NodeData)
                return
            end
            if event.SelectedNodes(1).NodeData(2) == false
                app.MDSHighLight(event.SelectedNodes(1).NodeData(1));
            else
                if numel(event.SelectedNodes(1).NodeData) < 3
                    app.ShowAssignment(event.SelectedNodes(1).NodeData(1));
                else
                    app.ShowAssignment(event.SelectedNodes(1).NodeData(1),event.SelectedNodes(1).NodeData(3));
                end
            end
            
        end

        % Callback function: Toggle3D
        function Toggle3DClicked(app, event)
            if event.Source == app.Toggle3D
                app.ThreeDMDSMenu.Checked = event.Source.State;
                Action = event.Source.State;
            else
                if app.ThreeDMDSMenu.Checked == "on"
                    Action = "off";
                else
                    Action = "on";
                end
                app.Toggle3D.State = Action;
                app.ThreeDMDSMenu.Checked = Action;
            end
            switch Action
                case "off"
                    ShowMDS(app,false);
                    view(app.MDSAxesUI,2);
%                    rotate3d(app.MDSAxesUI,'off');
                case "on"
                    ShowMDS(app,true);
                    view(app.MDSAxesUI,3);
            end
            [az,el] = view(app.MDSAxesUI);
            app.SliderMDSHorizontal.Value = -az;
            app.SliderMDSVertical.Value   = el;

        end

        % Window button motion function: MicrostatetemplateexplorerUIFigure
        function MicrostatetemplateexplorerUIFigureWindowButtonMotion(app, event)
            if app.TabGroup.SelectedTab == app.MDSTab
                pos = app.MDSAxesUI.CurrentPoint;

                inX = ~any(diff([app.MDSAxesUI.XLim(1) pos(1,1) app.MDSAxesUI.XLim(2)])<0);
                inY = ~any(diff([app.MDSAxesUI.YLim(1) pos(1,2) app.MDSAxesUI.YLim(2)])<0);
                if inX && inY
                    app.MDSMouseMove(pos(1,:));
                end
            end
        end

        % Callback function: ComputeMetaMapsMenu, PushToolCluster
        function PushToolClusterClicked(app, event)
            [FileName,PathName] = uigetfile("*.sxyz","Select SXYZ file",app.LastPath);
            if FileName == 0
                return
            end
            app.PushToolBroomClicked();
            [Montage,xyz,Labels] = ReadSXYZ(fullfile(PathName,FileName));
            if isempty(Montage)
                error('Cannot read file %s',p.Results.XYZFile);
            end
            MapCollection = [];
            app.DistMat.ClusterMaps = app.EmptySet(xyz,Labels);
            for Set = 1:numel(app.DistMat.TemplateSet)
                Weighting = app.DistMat.TemplateSet{Set}.msinfo.MetaData.nSubjects;

                ResamplingMat = MakeResampleMatrices(app.DistMat.TemplateSet{Set}.chanlocs,app.DistMat.ClusterMaps.chanlocs);
                MapsToAdd = app.DistMat.TemplateSet{Set}.msinfo.MSMaps(app.DistMat.nMaps(Set)).Maps * ResamplingMat';
                MapsToAdd = NormDim(MapsToAdd,2);
                MapCollection = [MapCollection;  MapsToAdd* Weighting]; %#ok<AGROW> 
            end
            MinMaps = min(app.DistMat.nMaps);
            MaxMaps = max(app.DistMat.nMaps);
            
            for nClusters = MinMaps:MaxMaps
                [app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).Maps,Assignment,~,app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).ExpVar] = eeg_kMeans(MapCollection,nClusters,500,[],'');
                app.DistMat.ClusterAssignment{nClusters} = Assignment;
                app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).ColorMap = lines(nClusters);
                for j = 1:nClusters
                    app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).Labels{j} = sprintf('MetaMap_%i.%i',nClusters,j);
                end
                app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).SortMode = 'none';
                app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).SortedBy = '';
                app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).SpatialCorrelation= [];
                app.DistMat.ClusterMaps.data = [app.DistMat.ClusterMaps.data app.DistMat.ClusterMaps.msinfo.MSMaps(nClusters).Maps'];
            end
            
            app.DistMat.ClusterMaps.nbchan                         = numel(app.DistMat.ClusterMaps.chanlocs);
            app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses     = MinMaps;
            app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses     = MaxMaps;
            app.DistMat.ClusterMaps.msinfo.ClustPar.GFPPeaks       = true;
            app.DistMat.ClusterMaps.msinfo.ClustPar.IgnorePolarity = true;
            app.DistMat.ClusterMaps.msinfo.ClustPar.MaxMaps        = NaN;
            app.DistMat.ClusterMaps.msinfo.ClustPar.Restarts       = 100;
            app.DistMat.ClusterMaps.msinfo.ClustPar.UseAAHC        = false;
            app.DistMat.ClusterMaps.msinfo.ClustPar.Normalize      = true;
            app.DistMat.ClusterMaps.msinfo.ClustPar.UseEMD         = false;
            app.DistMat.ClusterMaps.msinfo.children                = 'None';
            app.DistMat.ClusterMaps.trials                         = 1;
            app.DistMat.ClusterMaps.pnts                           = size(app.DistMat.ClusterMaps.data,2);
            app.ShowClusterMaps();
        end

        % Callback function: PushToolSwap, ReorderMetaMapsMenu
        function PushToolSwapClicked(app, event)
            if app.TabGroup.SelectedTab ~= app.MetaMapTab || isempty(app.SelectedMapIndex)
                return
            end
            
            nMaps = app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses:app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses;
            QuestText = cell(numel(nMaps),1);
            AnsText   = cell(numel(nMaps),1);

            for i = 1:numel(nMaps)
                QuestText(i) = {sprintf('%i Classes',nMaps(i))};
                AnsText(i)   = {sprintf('%i ',1:nMaps(i))};
            end
            
            Answers = inputdlg(QuestText,'New order',1,AnsText);

            if isempty(Answers)
                return;
            end

            for i = 1:numel(nMaps)
                NewOrder = abs(str2num(Answers{i})); %#ok<ST2NM> 
                NewPolarity = sign(str2num(Answers{i})); %#ok<ST2NM> 
                NewPolarity = repmat(NewPolarity(:),1,size(app.DistMat.ClusterMaps.msinfo.MSMaps(nMaps(i)).Maps,2));
                app.DistMat.ClusterMaps.msinfo.MSMaps(nMaps(i)).Maps = app.DistMat.ClusterMaps.msinfo.MSMaps(nMaps(i)).Maps(NewOrder,:).*NewPolarity;
            end

            app.RecomputeAssignment();
            app.ShowClusterMaps();
            return;
        end

        % Menu selected function: ExportMetaMapsMenu
        function ExportMetaMapsMenuSelected(app, event)
            if ~isfield(app.DistMat,'ClusterMaps')
                return
            else
                EEG = app.DistMat.ClusterMaps;
                [filename, pathname] = uiputfile('*.set', 'Pick an EEGLAB file name');
            
                if filename ~= 0
                    EEG.setname = strrep(filename,'.set','');
                    save(fullfile(pathname,filename),'EEG','-mat');
                end            
            end        
        end

        % Menu selected function: SavetoExcelFileMenu
        function SavetoExcelFileMenuSelected(app, event)
            if isempty(app.UITableFindings.Data)
                return
            end
            [filename, pathname] = uiputfile('*.xlsx', 'Pick an Excel file name to save data');
            if filename ~= 0
                SaveResultsToExcel(app, fullfile(pathname,filename));               
            end
        end

        % Menu selected function: ThreeDMDSMenu
        function ThreeDMDSMenuSelected(app, event)
            app.Toggle3DClicked(event);
        end

        % Callback function
        function SortTemplateMapsMenuSelected(app, event)
            Answer = inputdlg(sprintf('Number of template maps to use (%i - %i)',app.DistMat.ClusterMaps.msinfo.ClustPar.MinClasses,app.DistMat.ClusterMaps.msinfo.ClustPar.MaxClasses),'Sort template Maps');
            if isempty(Answer)
                return;
            end
            nMapsToUse = str2double(Answer);
            
            Polarity   = RecomputeAssignment(app,nMapsToUse);
            Polarity   = Polarity{nMapsToUse};
            Assignment = app.DistMat.ClusterAssignment{nMapsToUse};
            for i = 1:numel(app.DistMat.TemplateMap)
                app.DistMat.TemplateMap(i).Map = app.DistMat.TemplateMap(i).Map * Polarity(i);
                app.DistMat.TemplateMap(i).MetaClass = sprintf('%i.%i',nMapsToUse,Assignment(i));
            end
            uialert(app.MicrostatetemplateexplorerUIFigure,'Done','Sort template maps','Icon','success');

        end

        % Value changed function: SliderMDSHorizontal, SliderMDSVertical
        function SliderMDSValueChanged(app, event)
            az = -app.SliderMDSHorizontal.Value;
            el =  app.SliderMDSVertical.Value;

            view(app.MDSAxesUI,az,el);
        end

        % Value changed function: ButtonRotateRight
        function ButtonRotateRightValueChanged(app, event)
            value = app.ButtonRotateRight.Value;
            app.ButtonRotateLeft.Value = 0;
            switch value
                case 1
                      if strcmp(app.MyTimer.Running,'off')
                        start(app.MyTimer);
                      end
                case 0
                      if strcmp(app.MyTimer.Running,'on')
                        stop(app.MyTimer);
                      end

            end

        end

        % Value changed function: ButtonRotateLeft
        function ButtonRotateLeftValueChanged(app, event)
            value = app.ButtonRotateLeft.Value;
            app.ButtonRotateRight.Value = 0;

            switch value
                case 1
                      if strcmp(app.MyTimer.Running,'off')
                        start(app.MyTimer);
                      end
                case 0
                      if strcmp(app.MyTimer.Running,'on')
                        stop(app.MyTimer);
                      end
            end
            
        end

        % Callback function: FindClosestTool, NextbestguessMenu
        function FindClosestToolClicked(app, event)
            ExistingMaps = numel(app.mdsSelectedPtr);
            if ExistingMaps > 0 && ExistingMaps < size(app.DistMat.SharedVar,1)
                [~,SortOrder] = sort(app.DistMat.SharedVar(app.mdsSelectedPtr(1),app.MapsToShow),2,"descend");
                MapToAdd = SortOrder(ExistingMaps+1);
                MapIndexToAdd = app.MapsToShow(MapToAdd);
                app.MDSHighLight(MapIndexToAdd);

                app.MDSAxesUIButtonDown([]);    
            end
        end

        % Callback function: FiltertemplatesMenu, PushToolFilter
        function FiltertemplatesMenuSelected(app, event)
            figh = uifigure("WindowStyle","modal","Position",[300 300 300 300],'Name','Select filters');
            figGrid = uigridlayout(figh);
            Labels =       {"Cognition and Emotion","Consciousness and its Disorders","Neurological Disorders","Development and Aging","Psychiatric Disorders","Drugs and Brain Stimulation"};
            DomainFields = {'CognitionandEmotion'  ,'Consciousnessanditsdisorders',  'Neurologicaldisorders',  'Development',          'Psychiatricdisorders', 'Drugsandbrainstimulation'};

            nLabels = numel(Labels);
            if isempty(app.DomainFilter)
                app.DomainFilter = true(nLabels,1);
            end
            figGrid.ColumnWidth = {'1x'};
            figGrid.RowHeight   = repmat({'1x'},nLabels+1,1);
            
            for i = 1:nLabels
                txt{i} = uicheckbox(figGrid,"Text",Labels{i},"Value",app.DomainFilter(i)); %#ok<AGROW>
                txt{i}.Layout.Row    = i; %#ok<AGROW>
                txt{i}.Layout.Column = 1; %#ok<AGROW>
                txt{i}.UserData = DomainFields{i}; %#ok<AGROW>
            end
            ButtonGrid = uigridlayout(figGrid);
            ButtonGrid.Layout.Column = 1;
            ButtonGrid.Layout.Row    = nLabels + 1;
            ButtonGrid.ColumnWidth = {'1x','1x'};
            ButtonGrid.RowHeight   = {'1x'};
            ButtonGrid.Padding = [1 1 1 1];
            OKButton = uibutton(ButtonGrid,"Text","OK","ButtonPushedFcn",@(src,evt) app.FilterSettingsChanged(figh,txt));
            OKButton.ButtonPushedFcn = {@app.FilterSettingsChanged, figh,txt};
            OKButton.Layout.Row = 1;
            OKButton.Layout.Column = 1;
            
            CancelButton = uibutton(ButtonGrid,"Text","Cancel");
            CancelButton.ButtonPushedFcn = {@app.FilterSettingsChanged, figh,[]};
            CancelButton.Layout.Row = 1;
            CancelButton.Layout.Column = 2;
            

        end

        % Callback function: PushToolNoFilter
        function PushToolNoFilterClicked(app, event)
            app.DomainFilter = true(6,1);
            TemplateNames = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);

            ToBeUsed = 1:numel(app.DistMat.TemplateSet);          
            for i = 1:numel(ToBeUsed)
                app.SelectedMapIndex(app.DistMat.Onset(ToBeUsed(i)):app.DistMat.Offset(ToBeUsed(i))) = true;
            end
            app.SelectedMapIndex = find(app.SelectedMapIndex == true);
            app.SelectedTemplatesList.Value = TemplateNames(ToBeUsed);
            app.ShowCorrMatImage();
            app.SetupMDS(true);
            app.ShowMDS();
            app.PushToolBroomClicked();

        end

        % Close request function: MicrostatetemplateexplorerUIFigure
        function MicrostatetemplateexplorerUIFigureCloseRequest(app, event)
            if exist(app.LastPath,'dir')
                setpref('MSTemplateExplorer','TemplatePath',app.LastPath);
            end

            delete(app)
            
        end

        % Button pushed function: LimitresultstoselectionButton
        function LimitresultstoselectionButtonPushed(app, event)
            app.FilterSettingsChanged([],[],[],[],app.SelectedTemplatesList.Value);
        end

        % Menu selected function: CleartemplatefilterMenu
        function CleartemplatefilterMenuSelected(app, event)
            app.SelectedTemplatesList.Value = cellfun(@(x) x.setname,app.DistMat.TemplateSet,'UniformOutput',false);
            app.FilterSettingsChanged([],[],[],[],app.SelectedTemplatesList.Value);
        end

        % Menu selected function: ExportStudyListMenu
        function ExportStudyListMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Pick an EXCEL file name');
            if filename == 0
                return;
            end
            
            OutTable = [];
            for i = 1:numel(app.DistMat.TemplateSet)
                info = app.DistMat.TemplateSet{i}.msinfo;
                ID   = app.DistMat.TemplateSet{i}.setname;
                if isfield(info,'Citation')
                    C = info.Citation;
                    OutTable = [OutTable;{ID},{C.Title},{C.Year},{C.Authors},{C.Journal},{C.Pages},{C.DOI}];
                end

                OutCell = [{'ID'},{'Title'},{'Year'},{'Authors'},{'Journal'},{'Pages'},{'DOI'};OutTable];
                writecell(OutCell,fullfile(pathname,filename),"FileType","spreadsheet","WriteMode","overwritesheet","Sheet","Citations");
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.MicrostatetemplateexplorerUIFigureCloseRequest([])
        end

        % Menu selected function: AppendtoExcelFileMenu
        function AppendtoExcelFileMenuSelected(app, event)
            if isempty(app.UITableFindings.Data)
                return
            end
            [filename, pathname] = uigetfile('*.xlsx', 'Pick an Excel file name to append data');
            if filename ~= 0
                SaveResultsToExcel(app, fullfile(pathname,filename));               
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostatetemplateexplorerUIFigure = uifigure('Visible', 'off');
            app.MicrostatetemplateexplorerUIFigure.Name = 'Microstate template explorer';
            app.MicrostatetemplateexplorerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrostatetemplateexplorerUIFigureCloseRequest, true);
            app.MicrostatetemplateexplorerUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MicrostatetemplateexplorerUIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.MicrostatetemplateexplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadConfigurationMenu = uimenu(app.FileMenu);
            app.LoadConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolOpenClicked, true);
            app.LoadConfigurationMenu.Text = 'Load Configuration';
            app.SaveConfigurationMenu = uimenu(app.FileMenu);
            app.SaveConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolSaveClicked, true);
            app.SaveConfigurationMenu.Text = 'Save Configuration';
            app.ExportStudyListMenu = uimenu(app.FileMenu);
            app.ExportStudyListMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStudyListMenuSelected, true);
            app.ExportStudyListMenu.Separator = 'on';
            app.ExportStudyListMenu.Text = 'Export Study List';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.TemplatesMenu = uimenu(app.MicrostatetemplateexplorerUIFigure);
            app.TemplatesMenu.Text = 'Templates';
            app.TemplateEditorMenu = uimenu(app.TemplatesMenu);
            app.TemplateEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolEditClicked, true);
            app.TemplateEditorMenu.Separator = 'on';
            app.TemplateEditorMenu.Text = 'Template Editor';
            app.LoadTemplatesMenu = uimenu(app.TemplatesMenu);
            app.LoadTemplatesMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolComputeClicked, true);
            app.LoadTemplatesMenu.Text = 'Load Templates';
            app.AddTemplatesMenu = uimenu(app.TemplatesMenu);
            app.AddTemplatesMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolAddTemplateClicked, true);
            app.AddTemplatesMenu.Text = 'Add Templates';
            app.FiltertemplatesMenu = uimenu(app.TemplatesMenu);
            app.FiltertemplatesMenu.MenuSelectedFcn = createCallbackFcn(app, @FiltertemplatesMenuSelected, true);
            app.FiltertemplatesMenu.Separator = 'on';
            app.FiltertemplatesMenu.Text = 'Filter templates';
            app.CleartemplatefilterMenu = uimenu(app.TemplatesMenu);
            app.CleartemplatefilterMenu.MenuSelectedFcn = createCallbackFcn(app, @CleartemplatefilterMenuSelected, true);
            app.CleartemplatefilterMenu.Text = 'Clear template filter';
            app.MetaMapsMenu = uimenu(app.MicrostatetemplateexplorerUIFigure);
            app.MetaMapsMenu.Text = 'Meta-Maps';
            app.ComputeMetaMapsMenu = uimenu(app.MetaMapsMenu);
            app.ComputeMetaMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolClusterClicked, true);
            app.ComputeMetaMapsMenu.Text = 'Compute Meta-Maps';
            app.ReorderMetaMapsMenu = uimenu(app.MetaMapsMenu);
            app.ReorderMetaMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolSwapClicked, true);
            app.ReorderMetaMapsMenu.Text = 'Reorder Meta-Maps';
            app.ExportMetaMapsMenu = uimenu(app.MetaMapsMenu);
            app.ExportMetaMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMetaMapsMenuSelected, true);
            app.ExportMetaMapsMenu.Separator = 'on';
            app.ExportMetaMapsMenu.Text = 'Export Meta-Maps';
            app.ViewMenu = uimenu(app.MicrostatetemplateexplorerUIFigure);
            app.ViewMenu.Text = 'View';
            app.ThreeDMDSMenu = uimenu(app.ViewMenu);
            app.ThreeDMDSMenu.MenuSelectedFcn = createCallbackFcn(app, @ThreeDMDSMenuSelected, true);
            app.ThreeDMDSMenu.Text = '3D MDS';
            app.FindingsMenu = uimenu(app.MicrostatetemplateexplorerUIFigure);
            app.FindingsMenu.Text = 'Findings';
            app.ClearMenu = uimenu(app.FindingsMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolBroomClicked, true);
            app.ClearMenu.Text = 'Clear';
            app.NextbestguessMenu = uimenu(app.FindingsMenu);
            app.NextbestguessMenu.MenuSelectedFcn = createCallbackFcn(app, @FindClosestToolClicked, true);
            app.NextbestguessMenu.Separator = 'on';
            app.NextbestguessMenu.Text = 'Next best guess';
            app.SavetoExcelFileMenu = uimenu(app.FindingsMenu);
            app.SavetoExcelFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoExcelFileMenuSelected, true);
            app.SavetoExcelFileMenu.Text = 'Save to Excel File';
            app.AppendtoExcelFileMenu = uimenu(app.FindingsMenu);
            app.AppendtoExcelFileMenu.MenuSelectedFcn = createCallbackFcn(app, @AppendtoExcelFileMenuSelected, true);
            app.AppendtoExcelFileMenu.Text = 'Append to Excel File';
            app.Toolbar = uitoolbar(app.MicrostatetemplateexplorerUIFigure);
            app.PushToolOpen = uipushtool(app.Toolbar);
            app.PushToolOpen.ClickedCallback = createCallbackFcn(app, @PushToolOpenClicked, true);
            app.PushToolOpen.Icon = fullfile(pathToMLAPP, 'Open.jpg');
            app.PushToolSave = uipushtool(app.Toolbar);
            app.PushToolSave.Tooltip = {'Save configuration to file'};
            app.PushToolSave.ClickedCallback = createCallbackFcn(app, @PushToolSaveClicked, true);
            app.PushToolSave.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.PushToolEdit = uipushtool(app.Toolbar);
            app.PushToolEdit.ClickedCallback = createCallbackFcn(app, @PushToolEditClicked, true);
            app.PushToolEdit.Icon = fullfile(pathToMLAPP, 'Edit.png');
            app.PushToolEdit.Separator = 'on';
            app.PushToolCompute = uipushtool(app.Toolbar);
            app.PushToolCompute.Tooltip = {'Compute configuration based on templates'};
            app.PushToolCompute.ClickedCallback = createCallbackFcn(app, @PushToolComputeClicked, true);
            app.PushToolCompute.Icon = fullfile(pathToMLAPP, 'Compute.png');
            app.PushToolAddTemplate = uipushtool(app.Toolbar);
            app.PushToolAddTemplate.ClickedCallback = createCallbackFcn(app, @PushToolAddTemplateClicked, true);
            app.PushToolAddTemplate.Icon = fullfile(pathToMLAPP, 'AddTemplate.png');
            app.PushToolPlus = uipushtool(app.Toolbar);
            app.PushToolPlus.Tooltip = {'More contrast'};
            app.PushToolPlus.ClickedCallback = createCallbackFcn(app, @PushToolPlusClicked, true);
            app.PushToolPlus.Icon = fullfile(pathToMLAPP, 'Plus.png');
            app.PushToolPlus.Separator = 'on';
            app.PushToolMinus = uipushtool(app.Toolbar);
            app.PushToolMinus.Tooltip = {'Less contrast'};
            app.PushToolMinus.ClickedCallback = createCallbackFcn(app, @PushToolMinusClicked, true);
            app.PushToolMinus.Icon = fullfile(pathToMLAPP, 'Minus.png');
            app.PushToolFilter = uipushtool(app.Toolbar);
            app.PushToolFilter.ClickedCallback = createCallbackFcn(app, @FiltertemplatesMenuSelected, true);
            app.PushToolFilter.Icon = fullfile(pathToMLAPP, 'Filter.png');
            app.PushToolFilter.Separator = 'on';
            app.PushToolNoFilter = uipushtool(app.Toolbar);
            app.PushToolNoFilter.ClickedCallback = createCallbackFcn(app, @PushToolNoFilterClicked, true);
            app.PushToolNoFilter.Icon = fullfile(pathToMLAPP, 'NoFilter.png');
            app.FindClosestTool = uipushtool(app.Toolbar);
            app.FindClosestTool.Tooltip = {'Search for nearest neighbor'};
            app.FindClosestTool.ClickedCallback = createCallbackFcn(app, @FindClosestToolClicked, true);
            app.FindClosestTool.Enable = 'off';
            app.FindClosestTool.Icon = fullfile(pathToMLAPP, 'binoculars.png');
            app.FindClosestTool.Separator = 'on';
            app.PushToolBroom = uipushtool(app.Toolbar);
            app.PushToolBroom.Tooltip = {'Clear selected maps'};
            app.PushToolBroom.ClickedCallback = createCallbackFcn(app, @PushToolBroomClicked, true);
            app.PushToolBroom.Enable = 'off';
            app.PushToolBroom.Icon = fullfile(pathToMLAPP, 'Broom.png');
            app.Toggle3D = uitoggletool(app.Toolbar);
            app.Toggle3D.Tooltip = {'Toogle 3D MDS'};
            app.Toggle3D.ClickedCallback = createCallbackFcn(app, @Toggle3DClicked, true);
            app.Toggle3D.Icon = fullfile(pathToMLAPP, '3D.png');
            app.Toggle3D.Separator = 'on';
            app.PushToolCluster = uipushtool(app.Toolbar);
            app.PushToolCluster.ClickedCallback = createCallbackFcn(app, @PushToolClusterClicked, true);
            app.PushToolCluster.Icon = fullfile(pathToMLAPP, 'Cluster.png');
            app.PushToolCluster.Separator = 'on';
            app.PushToolSwap = uipushtool(app.Toolbar);
            app.PushToolSwap.ClickedCallback = createCallbackFcn(app, @PushToolSwapClicked, true);
            app.PushToolSwap.Icon = fullfile(pathToMLAPP, 'Swap.png');
            app.GridLayout = uigridlayout(app.MicrostatetemplateexplorerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimilarityMatrixTab = uitab(app.TabGroup);
            app.SimilarityMatrixTab.Title = 'Similarity Matrix';
            app.GridLayoutCMat = uigridlayout(app.SimilarityMatrixTab);
            app.UICormatAxes = uiaxes(app.GridLayoutCMat);
            app.UICormatAxes.ButtonDownFcn = createCallbackFcn(app, @UICormatAxesButtonDown, true);
            app.GridLayout6 = uigridlayout(app.GridLayoutCMat);
            app.UIAxesCM1 = uiaxes(app.GridLayout6);
            app.UIAxesCM1.XTick = [];
            app.UIAxesCM1.YTick = [];
            app.UIAxesCM2 = uiaxes(app.GridLayout6);
            app.UIAxesCM2.XTick = [];
            app.UIAxesCM2.YTick = [];
            app.LimitresultstoselectionButton = uibutton(app.GridLayout6, 'push');
            app.LimitresultstoselectionButton.ButtonPushedFcn = createCallbackFcn(app, @LimitresultstoselectionButtonPushed, true);
            app.LimitresultstoselectionButton.Text = 'Limit results to selection';
            app.MDSTab = uitab(app.TabGroup);
            app.MDSTab.Title = 'MDS';
            app.GridLayoutMDS = uigridlayout(app.MDSTab);
            app.StudyTree = uitree(app.GridLayoutMDS);
            app.StudyTree.SelectionChangedFcn = createCallbackFcn(app, @StudyTreeSelectionChanged, true);
            app.StudyTree.ClickedFcn = createCallbackFcn(app, @StudyTreeClicked, true);
            app.StudyTree.DoubleClickedFcn = createCallbackFcn(app, @StudyTreeDoubleClicked, true);
            app.GridLayoutMDSMaps = uigridlayout(app.GridLayoutMDS);
            app.GridLayoutMDSAxControl = uigridlayout(app.GridLayoutMDS);
            app.MDSAxesUI = uiaxes(app.GridLayoutMDSAxControl);
            app.MDSAxesUI.ButtonDownFcn = createCallbackFcn(app, @MDSAxesUIButtonDown, true);
            app.MDSAxesUI.Visible = 'off';
            app.GridLayoutMDSHorRor = uigridlayout(app.GridLayoutMDSAxControl);
            app.SliderMDSHorizontal = uislider(app.GridLayoutMDSHorRor);
            app.SliderMDSHorizontal.Limits = [-180 180];
            app.SliderMDSHorizontal.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.SliderMDSHorizontal.ValueChangedFcn = createCallbackFcn(app, @SliderMDSValueChanged, true);
            app.SliderMDSHorizontal.MinorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.ButtonRotateRight = uibutton(app.GridLayoutMDSHorRor, 'state');
            app.ButtonRotateRight.ValueChangedFcn = createCallbackFcn(app, @ButtonRotateRightValueChanged, true);
            app.ButtonRotateRight.Text = '<<';
            app.ButtonRotateLeft = uibutton(app.GridLayoutMDSHorRor, 'state');
            app.ButtonRotateLeft.ValueChangedFcn = createCallbackFcn(app, @ButtonRotateLeftValueChanged, true);
            app.ButtonRotateLeft.Text = '>>';
            app.Label_2 = uilabel(app.GridLayoutMDSAxControl);
            app.Label_2.Text = '';
            app.SliderMDSVertical = uislider(app.GridLayoutMDSAxControl);
            app.SliderMDSVertical.Limits = [-180 180];
            app.SliderMDSVertical.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.SliderMDSVertical.ValueChangedFcn = createCallbackFcn(app, @SliderMDSValueChanged, true);
            app.SliderMDSVertical.MinorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.ButtonSwapAxes = uibutton(app.GridLayoutMDSAxControl, 'push');
            app.ButtonSwapAxes.Text = 'Swap Axes';
            app.FindingsTab = uitab(app.TabGroup);
            app.FindingsTab.Title = 'Findings';
            app.GridLayout7 = uigridlayout(app.FindingsTab);
            app.UITableFindings = uitable(app.GridLayout7);
            app.UITableFindings.ColumnName = {'Label'; 'Contrast'; 'Effect'; 'Similarity'};
            app.UITableFindings.RowName = {};
            app.UITableFindings.ColumnSortable = [true true true true];
            app.UITableFindings.ColumnEditable = [false false false false];
            app.UITableCitations = uitable(app.GridLayout7);
            app.UITableCitations.ColumnName = {'ID'; 'Title'; 'Year'; 'Authors'; 'Journal'; 'Pages'; 'DOI'; 'KeyWords'};
            app.UITableCitations.RowName = {};
            app.UITableCitations.ColumnSortable = [true true true true true true];
            app.UITableCitations.ColumnEditable = [false false false false false false];
            app.UITableCitations.DoubleClickedFcn = createCallbackFcn(app, @UITableCitationsDoubleClicked, true);
            app.MetaMapTab = uitab(app.TabGroup);
            app.MetaMapTab.Title = 'Meta Clusters';
            app.GridLayoutMetaMaps = uigridlayout(app.MetaMapTab);
            % Show the figure after all components are created
            app.MicrostatetemplateexplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSTemplateExplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostatetemplateexplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostatetemplateexplorerUIFigure)
        end
    end
end